classdef Confint_parameters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ConfidenceIntervalParametersPanel  matlab.ui.container.Panel
        CIAlgorithmDropDownLabel        matlab.ui.control.Label
        CIAlgorithmDropDown             matlab.ui.control.DropDown
        SizeofBootstrapsEditFieldLabel  matlab.ui.control.Label
        SizeofBootstrapsEditField       matlab.ui.control.NumericEditField
        NoofBootstrapsEditFieldLabel    matlab.ui.control.Label
        NoofBootstrapsEditField         matlab.ui.control.NumericEditField
        SignificancelevelinEditFieldLabel  matlab.ui.control.Label
        SignificancelevelinEditField    matlab.ui.control.NumericEditField
        BootstrapSizeinEditFieldLabel   matlab.ui.control.Label
        BootstrapSizeinEditField        matlab.ui.control.NumericEditField
        DoneButton                      matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
    end

    
    properties
        Size_of_Bootstraps % Size of bootstrap for bootstrap algorithm
        No_of_Bootstraps   % Number of bootstraps ''
        Alpha              % (100-Significance) in percent
        Bootstrap_percent  %  
    end
    
    methods
        function UIState(app)
            app.CIAlgorithmDropDown.Value          = 1;
            app.SizeofBootstrapsEditField.Value    = app.Size_of_Bootstraps;
            app.NoofBootstrapsEditField.Value      = app.No_of_Bootstraps;
            app.SignificancelevelinEditField.Value = app.Alpha;
            app.BootstrapSizeinEditField.Value     = app.Bootstrap_percent;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, sizeBoot, number, alpha, sizepercent)
            app.Size_of_Bootstraps = sizeBoot;
            app.No_of_Bootstraps   = number;
            app.Alpha              = alpha;
            app.Bootstrap_percent  = sizepercent;
            app.UIState();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ConfidenceIntervalParametersPanel = uipanel(app.UIFigure);
            app.ConfidenceIntervalParametersPanel.Title = 'Confidence Interval Parameters';
            app.CIAlgorithmDropDownLabel = uilabel(app.ConfidenceIntervalParametersPanel);
            app.CIAlgorithmDropDownLabel.Text = 'C.I. Algorithm';
            app.CIAlgorithmDropDown = uidropdown(app.ConfidenceIntervalParametersPanel);
            app.CIAlgorithmDropDown.Items = {'Select Type', 'BootStrapping', '', ''};
            app.CIAlgorithmDropDown.Value = 'Select Type';
            app.SizeofBootstrapsEditFieldLabel = uilabel(app.ConfidenceIntervalParametersPanel);
            app.SizeofBootstrapsEditFieldLabel.Text = 'Size of Bootstraps';
            app.SizeofBootstrapsEditField = uieditfield(app.ConfidenceIntervalParametersPanel, 'numeric');
            app.NoofBootstrapsEditFieldLabel = uilabel(app.ConfidenceIntervalParametersPanel);
            app.NoofBootstrapsEditFieldLabel.Text = 'No. of Bootstraps';
            app.NoofBootstrapsEditField = uieditfield(app.ConfidenceIntervalParametersPanel, 'numeric');
            app.SignificancelevelinEditFieldLabel = uilabel(app.ConfidenceIntervalParametersPanel);
            app.SignificancelevelinEditFieldLabel.Text = 'Significance level in %';
            app.SignificancelevelinEditField = uieditfield(app.ConfidenceIntervalParametersPanel, 'numeric');
            app.BootstrapSizeinEditFieldLabel = uilabel(app.ConfidenceIntervalParametersPanel);
            app.BootstrapSizeinEditFieldLabel.Text = 'Bootstrap Size in %';
            app.BootstrapSizeinEditField = uieditfield(app.ConfidenceIntervalParametersPanel, 'numeric');
            app.DoneButton = uibutton(app.ConfidenceIntervalParametersPanel, 'push');
            app.DoneButton.Text = 'Done';
            app.CancelButton = uibutton(app.ConfidenceIntervalParametersPanel, 'push');
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Confint_parameters(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end