classdef Cross_validation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        UIAxes                 matlab.ui.control.UIAxes
        LoadDataDropDownLabel  matlab.ui.control.Label
        LoadDataDropDown       matlab.ui.control.DropDown
        CrossvalidateButton    matlab.ui.control.Button
        FileLabel              matlab.ui.control.Label
        TextArea               matlab.ui.control.TextArea
        UIAxes2                matlab.ui.control.UIAxes
        ClearSessionButton     matlab.ui.control.Button
    end

    
    properties (Access = private)
        IsFileLoaded = 0;
        IsCrossValidatePushed = 0;
        CallingApp % Description
        XPCAobj
        data = [];
        data_label
        predictdata
        CrossvalidateButtonPushedState=0;
    end
    
    methods
        function loadstate(app)
            if(~isempty(app.data) && app.CrossvalidateButtonPushedState)
                CrossvalidateButtonPushed(app, matlab.ui.eventdata.ButtonPushedData);
            end
        
        end
        
        function [Data, Data_Label, CrossButtonState] = GetCrossDataAndState(app)
            Data = app.data;
            Data_Label = app.data_label;
            CrossButtonState = app.CrossvalidateButtonPushedState;
        end
        
        function UIstate(app)
            if(app.IsFileLoaded == 1)
                app.LoadDataDropDown.Enable = 'off';
                if(app.CrossvalidateButtonPushedState == 1)
                    app.CrossvalidateButton.Enable = 'off';
                else
                    app.CrossvalidateButton.Enable = 'on';
                end
            else
                app.CrossvalidateButton.Enable = 'off';
            end
        end
        
        function GetWorkspaceVariable(app, varName)
            
            app.data = evalin('base',varName);
            app.LoadDataDropDown.Enable = false;
            app.FileLabel.Text = "Variable Loaded: " + varName;
            app.FileLabel.Tooltip = app.FileLabel.Text;
            app.EnableModelConfigurationPanel();
            app.ReloadUIAndInitializeDataObjects();
            app.RefreshModelEstimationPanel();
            
        end
        
        function CancelWorkSpace(app)
            app.LoadDataDropDown.Value = "Select Input Type";
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, xpcaobj, Cross_Data, Cross_Data_label, CrossValidButtonState)
            app.UIFigure.Position = [400 100 620 419];
            app.CallingApp = mainapp;
            app.XPCAobj = xpcaobj;
            app.data = Cross_Data;
            app.data_label = Cross_Data_label;
            
            if(isempty(app.data))
                app.IsFileLoaded = 0;
            else
                app.IsFileLoaded = 1;
                app.FileLabel.Text = "File Loaded: " + Cross_Data_label;
            end
            
            app.CrossvalidateButtonPushedState = CrossValidButtonState;
            app.loadstate();
            app.UIstate();
        end

        % Value changed function: LoadDataDropDown
        function LoadDataDropDownValueChanged(app, event)
            value = app.LoadDataDropDown.Value;
            
            if (value ==  "Data File")
                [file,path] = uigetfile('*.mat');
                filename = fullfile(path, file);
                
                S = load(filename);
                names = fieldnames(S);
                
                
                if (length(names) == 1)
                    
                    app.data = S.(names{1});
                    app.LoadDataDropDown.Enable = false;
                    app.FileLabel.Text = "File Loaded: " + file;
                    app.FileLabel.Tooltip = app.FileLabel.Text;
                    %CrossValidationData(app.CallingApp, app.data);
                    app.IsFileLoaded = 1;
                    app.data_label = file;
                else
                    if (numel(S) == 0)
                        warndlg('No data variable in the file','Warning');
                        app.LoadDataDropDown.Value = "Select Input Type";
                        return;
                    else
                        warndlg('Check to see if the input file contains only one variable','Warning');
                        app.LoadDataDropDown.Value = "Select Input Type";
                        return;
                    end
                end

                
            elseif (value ==  "Load from Environment")
                WorkSpaceApp(app);
            end
            
            app.UIstate();
        end

        % Button pushed function: CrossvalidateButton
        function CrossvalidateButtonPushed(app, event)
           app.CrossvalidateButtonPushedState = 1;
            Dat = app.data;
            Dat_out = Dat(:,2);
            [~,app.predictdata,~] = app.XPCAobj.m_computeResidualsCrossValid(app.data);
            %app.predictdata
            
            %predicted_data = app.predictdata;
            %uisave('Dat_out','Vipul_Original_data');
            %uisave('predicted_data', 'vipul_data_prediction');
        
            a1 = plot(app.UIAxes, app.predictdata,'-r'); 
            hold (app.UIAxes,'on')
            a2 = plot(app.UIAxes, Dat_out,'--b');  %for vipul's data this is correct
            set(app.UIAxes.Legend,'Visible','on');
            legend(app.UIAxes,'Predicted Data','Original Data');
           
            %hold off    %this was creating an unnecessary figure why?
            %scatter(Dat_out, app.predictdata)
            plot(app.UIAxes2, Dat_out, app.predictdata,'o');
            
            cost_func = 'NRMSE';
            fit = round(goodnessOfFit(app.predictdata, Dat_out, cost_func)*100,2);
            fit = strcat('NRMSE fit: ',num2str(fit),'%');
            app.TextArea.Value = fit;
            app.TextArea.Editable = 'off';
            app.CrossvalidateButton.Enable = false;
            
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
            
        end

        % Button pushed function: ClearSessionButton
        function ClearSessionButtonPushed(app, event)
            app.IsFileLoaded                   = 0;
            app.IsCrossValidatePushed          = 0;
            app.data                           = [];
            app.data_label                     = '';
            app.predictdata                    = [];
            app.CrossvalidateButtonPushedState = 0;
            
%             app.UIAxes.Children.Visible = 'off';
%             app.UIAxes.Children.Visible = 'off';
%             %delete(app.UIAxes.Children(2))
%             app.UIAxes.Visible   = 'off';
            h1 = app.UIAxes.Children;
            empty_status_1_c = isempty(h1);
            %a = isempty(get(h1, 'Children'))
            h2 = app.UIAxes2.Children;
            empty_status_2_c = isempty(h2);


%             if(exist_status_1 ~= 0)
%                 app.UIAxes.Visible = "off";
%             end
            
            if(empty_status_1_c == 0)
                set(app.UIAxes.Children,'Visible' , 'off');
                set(app.UIAxes.Legend,'Visible','off');
            end
            
%             if(exist_status_2 ~= 0)
%                 app.UIAxes2.Visible = "off";
%             end
            
            if(empty_status_2_c == 0)
                app.UIAxes2.Children.Visible = 'off';
            end
            
%             app.UIAxes2.Children.Visible = 'off';
%             delete(app.UIAxes.Children)
%             delete(app.UIAxes.Legend)
            
            app.TextArea.Value                = '';
            app.TextArea.Editable             = 'off';
            %app.CrossvalidateButton.Enable    = true;
            
            app.LoadDataDropDown.Enable       = true;
            app.FileLabel.Text                = '';
            app.FileLabel.Tooltip             = app.FileLabel.Text;
            
            app.LoadDataDropDown.Value        ='Select Input Type';
            app.UIstate();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Cross Validation Comparison Plot')
            xlabel(app.UIAxes, 'Input')
            ylabel(app.UIAxes, 'Output')
            app.LoadDataDropDownLabel = uilabel(app.UIFigure);
            app.LoadDataDropDownLabel.Text = 'Load Data';
            app.LoadDataDropDown = uidropdown(app.UIFigure);
            app.LoadDataDropDown.Items = {'Select Input Type', 'Data File', 'Load from Environment'};
            app.LoadDataDropDown.ValueChangedFcn = createCallbackFcn(app, @LoadDataDropDownValueChanged, true);
            app.LoadDataDropDown.Value = 'Select Input Type';
            app.CrossvalidateButton = uibutton(app.UIFigure, 'push');
            app.CrossvalidateButton.ButtonPushedFcn = createCallbackFcn(app, @CrossvalidateButtonPushed, true);
            app.CrossvalidateButton.Text = 'Cross validate';
            app.FileLabel = uilabel(app.UIFigure);
            app.FileLabel.Text = '';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.TextArea.Tooltip = {'Goodness of Fit'};
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Scatter Plot')
            xlabel(app.UIAxes2, 'Actual Data')
            ylabel(app.UIAxes2, 'Estimated Data')
            app.UIAxes2.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.ClearSessionButton = uibutton(app.UIFigure, 'push');
            app.ClearSessionButton.ButtonPushedFcn = createCallbackFcn(app, @ClearSessionButtonPushed, true);
            app.ClearSessionButton.Text = 'Clear Session';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Cross_validation(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end