classdef OutputApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        ErrorVariancesTab       matlab.ui.container.Tab
        UITable                 matlab.ui.control.Table
        ModelTab                matlab.ui.container.Tab
        UITable2                matlab.ui.control.Table
        ModelDisplayArea        matlab.ui.control.TextArea
        ConfidenceIntervalsTab  matlab.ui.container.Tab
        UITable3                matlab.ui.control.Table
        Image                   matlab.ui.control.Image
        Image2                  matlab.ui.control.Image
        EigenvaluePlotPanel     matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
        FileLabel_2             matlab.ui.control.Label
        FileLabel_3             matlab.ui.control.Label
    end


    properties (Access = private)
        CallingApp;
        
        ModelType;
        
        Order;
        P_Value;
        EigenValues;
        ErrorVariances;
        Model;
        ConfidenceIntervals;
        confidence_intervals_rounded;
        no_of_Output_stat;
        no_of_Input_stat;
        
        lag;
        coeff;
        noise_model;
        scaled_eig_values;
        number_of_variables;
        y_array = [];
        u_array = []; 
        stringarr = [];
        stry = [];
        stru = [];  
        temp_string;
        coeff_data;
        
        num_err_var;
        err_var_array;
        err_var_stringarr;
        err_str;        
        
        model_rounded;
        
    end

    methods (Access = public)
        
        function SetModelType(app, model_type)
            app.ModelType = model_type;
        end
        
        function SetOrder(app, order)
            %disp('In SetOrder')
            app.Order = order;
        end
        
        function SetP_Value(app,p_value)
            app.P_Value = p_value;
        end

        function DisplayEigenValues(app, eigen_values,OrderType)
%             eigen_values
%             disp('In DisplayEigenValues')
              %OrderType
              if(OrderType == 0)
                    app.UIAxes.Visible = 'on';
                    app.EigenValues         = eigen_values;
                    
                    app.noise_model = NoiseModel.getInstance;
                    EigenNumber = 1:size(app.EigenValues);
                    
                    if (app.noise_model.m_getNoiseModel() == NoiseModelEnums.NOISY)
                        %app.UIAxes
                        plot(app.UIAxes ,EigenNumber, log(app.EigenValues))
                    else
                        app.UIAxes.YLabel.String = "\lambda / \lambda_{max}";
                        app.ErrorVariancesTab.Parent = [];
                        app.scaled_eig_values = app.EigenValues/max(app.EigenValues);
                        %app.UIAxes
                        plot(app.UIAxes , app.scaled_eig_values);
                    end
                    
                    if(app.ModelType == ModelTypeEnums.DYNAMIC)
                        app.UIAxes.Title.String = "Plot of Log of Eigenvalues" ;
                    else
                        total_vars = app.CallingApp.GetTotalNumberofVariables();
                        app.UIAxes.Title.String = "Plot of Eigenvalues. " + "Number of Constraints = " + num2str(total_vars - app.Order) ;
                    end
                    app.FileLabel_2.Text  = "Estimated Order: "+num2str(app.Order);
              else
                  app.UIAxes.Visible = 'off';
                  app.FileLabel_2.Text  = "Input Order: "+num2str(app.Order);
              end
        end


        function DisplayErrorVariances(app, error_variances,no_of_inputs,no_of_outputs)
            %disp('In DisplayErrorVariances')
            
            app.ResizeWindow();
            app.ShowTabGroup();
            
            %refresh(app.UIFigure)
            
            app.TabGroup.SelectedTab = app.ErrorVariancesTab;
            if (app.ModelType == ModelTypeEnums.DYNAMIC)
                app.ErrorVariances       = error_variances;
                %error_variances
                
                app.num_err_var        = size(error_variances, 1);
                
                app.err_var_array     = [];
                app.err_var_stringarr = [];
                
                
                for i=1:no_of_inputs
                    app.err_str    = [];
                    app.err_str    = "Input " + i ;
                    app.err_var_stringarr = [app.err_var_stringarr ; app.err_str];
                end
                
                for i=1:no_of_outputs
                    app.err_str    = [];
                    app.err_str    = "Output " + i ;
                    app.err_var_stringarr = [app.err_var_stringarr ; app.err_str];
                end
                
                app.UITable.Data   = [cellstr(app.err_var_stringarr) num2cell(app.ErrorVariances)];
            else
                app.ErrorVariances       = error_variances;
                %error_variances
                
                app.num_err_var        = size(error_variances, 1);
                
                app.err_var_array     = [];
                app.err_var_stringarr = [];
                
                
                for i=1:no_of_inputs
                    app.err_str    = [];
                    app.err_str    = "Input " + i ;
                    app.err_var_stringarr = [app.err_var_stringarr ; app.err_str];
                end
                
                for i=1:no_of_outputs
                    app.err_str    = [];
                    app.err_str    = "Output " + i ;
                    app.err_var_stringarr = [app.err_var_stringarr ; app.err_str];
                end
                
                app.UITable.Data   = [cellstr(app.err_var_stringarr) num2cell(app.ErrorVariances)];
            end
        end
    
        function DisplayModel(app, model)
            %disp('In DisplayModel')
            app.TabGroup.SelectedTab = app.ModelTab;
%            app.Model                = model/model(size(model,1),1);
            app.Model                = model;
            %app.Model
                        
            %model
            
            if (app.ModelType == ModelTypeEnums.DYNAMIC)
                app.lag        = size(model,1)/2 - 1;
                app.coeff      = app.Model;
                
                app.y_array     = [];
                app.u_array     = [];
                app.stringarr   = [];
                
                for i=1:1:app.lag
                    app.stry    = [];
                    app.stru    = [];
                    app.stry    = "y(k-" + num2str(i) + ")";
                    app.stru    = "u(k-" + num2str(i) + ")";
                    app.y_array = [app.y_array ; app.stry];
                    app.u_array = [app.u_array ; app.stru];
                end
                
                app.y_array     = ["y(k)"; app.y_array];
                app.u_array     = ["u(k)"; app.u_array];
                app.stringarr   = [ app.u_array; app.y_array];
                app.coeff_data      = [ num2cell(app.coeff) app.stringarr];
                app.UITable2.Visible = "on";
                app.UITable2.Data   = [cellstr(app.stringarr) num2cell(app.coeff)];
                app.ModelDisplayArea.Visible = "off";
            else
                app.no_of_Output_stat = size(model,1);
                app.no_of_Input_stat  = app.Order;
                
                t1 = 1:app.no_of_Input_stat;
                t2 = 1:app.no_of_Output_stat;
                                
                columnnames    = "Input " + t1;
                rownames       = "Output "+ t2;
                
                
                app.UITable2.RowName    = rownames;
                
                app.UITable2.ColumnName = columnnames;
                
                app.UITable2.Data       = model;
%                 app.UITable2.Visible             = 'off';
%                 app.ModelDisplayArea.Visible     = 'on';
%                 %for i= 1:app.number_of_variables
%                 %    app.temp_string = "Error Variance in Column  " + i ;
%                 %    app.stringarr   = [ app.stringarr; app.temp_string];
%                 %end
%                 app.model_rounded = round(model', 2);
%                 app.ModelDisplayArea.Value = mat2str(app.model_rounded);

                  
            end
            
           
        end
        
        function DeleteConfIntAndGraph(app,knownOrder)
           
            app.UITable3.Data = [];
            h1 = app.UIAxes;
            empty_status_1 = ~isempty(h1); %1 is not empty, 0 is empty
            %ismpty(app.UIAxes.Children)
            %exist_status_1_c = exist('app.UIAxes.Children')
            if(knownOrder == 1)
%                 if(exist_status_1_c ~= 0)
%                     
%                 end
                
                if(empty_status_1 ~= 0)
                    app.UIAxes.Children.Visible = "off";
                    app.UIAxes.Visible = "off";
                end
                
                
            else
                
                if(empty_status_1 == 0)
                    app.UIAxes.Visible = "on";
                    app.UIAxes.Children.Visible = "on";
                end
                
%                 if(exist_status_1_c ~= 0)
%                     
%                 end
            end
            app.FileLabel_3.Text = '';
        end
    
        function DisplayConfidenceIntervals(app, confidence_intervals)
            %disp('In DisplayConfidenceIntervals')
            
                app.ConfidenceIntervalsTab.Parent   = app.TabGroup;
                app.TabGroup.SelectedTab            = app.ConfidenceIntervalsTab;
                app.ConfidenceIntervals             = confidence_intervals;
                %confidence_intervals


                 if (app.ModelType == ModelTypeEnums.DYNAMIC)
                     app.confidence_intervals_rounded = round(confidence_intervals, 2);
                     app.confidence_intervals_rounded(isnan(app.confidence_intervals_rounded)) = [1,1];
                     colorArray = app.getBackgroundColor(app.confidence_intervals_rounded);
                    app.lag        = size(confidence_intervals,1)/2 - 1;
                    
                    
                    app.y_array     = [];
                    app.u_array     = [];
                    app.stringarr   = [];
                    app.coeff       = [];
                    
                    for i=1:size(app.confidence_intervals_rounded,1)
                        
                        app.temp_string      = "[" + num2str(app.confidence_intervals_rounded(i,1)) ...
                                                + " , " + num2str(app.confidence_intervals_rounded(i,2)) + "]";
                                           
                        app.coeff        = [ app.coeff ; app.temp_string]; 
                        
                    end
                    
                    for i=1:1:app.lag
                        
                        app.stry    = [];
                        app.stru    = [];
                        app.stry    = "y(k-" + num2str(i) + ")";
                        app.stru    = "u(k-" + num2str(i) + ")";
                        app.y_array = [app.y_array ; app.stry];
                        app.u_array = [app.u_array ; app.stru];
                        
                    end
                    
                    app.y_array     = ["y(k)"; app.y_array];
                    app.u_array     = ["u(k)"; app.u_array];
                    app.stringarr   = [ app.u_array; app.y_array]; 
                    app.coeff_data      = [ cellstr(app.coeff) app.stringarr];
                    app.UITable3.Data   = [cellstr(app.stringarr) cellstr(app.coeff)];
                    
                    app.UITable3.BackgroundColor = colorArray;
                    
                    
                 else
                    app.confidence_intervals_rounded = round(confidence_intervals, 3);
                    app.no_of_Output_stat = size(app.confidence_intervals_rounded,1);
                    app.no_of_Input_stat  = size(app.confidence_intervals_rounded,2);
                    
                    t1 = 1:app.no_of_Input_stat;
                    t2 = 1:app.no_of_Output_stat;
                                    
                    columnnames    = "Input " + t1;
                    rownames       = "Output "+ t2;
                    
                    
                    app.UITable3.RowName    = rownames;
                    
                    app.UITable3.ColumnName = columnnames;


                    for i=1:size(app.confidence_intervals_rounded,1)
                        
                        for j=1:size(app.confidence_intervals_rounded,2)
                        
                            temp_string2(i,j)      = "[" + num2str(app.confidence_intervals_rounded(i,j,1)) ...
                                                    + " , " + num2str(app.confidence_intervals_rounded(i,j,2)) + "]";
                        end
                    end
                    temp_string_char  = cellstr(temp_string2);
                    app.UITable3.Data = temp_string_char;
                 end
           app.FileLabel_3.Text  = "Alpha-value: "+num2str(app.P_Value);
        end
        
        function DisplayResiduals(app)
             app.TabGroup.SelectedTab            = app.ResidualsTab;
        end
        
        function coloArray = getBackgroundColor(app,confidence_interval_data)
             n = size(confidence_interval_data);
             arr = [];
             for i = 1:n
                 if(confidence_interval_data(i,1)<0 && confidence_interval_data(i,2)>0)
                     arr = [arr;1,0,0];
                 else
                     arr = [arr;0,1,0];
                 end
             end
             coloArray = arr;
        end
    end

    methods (Access = private)
    
         function ShowTabGroup(app)
            app.TabGroup.Position               = [1 1 450 300];
            app.TabGroup.Visible                = 'on';
        end
    
        function ResizeWindow(app)
            
            app.EigenvaluePlotPanel.Position    = [1   350 450 350];
            app.UIFigure.Position               = [685 100 450 700];
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.UIFigure.Position = [685 450 450 350];
            app.TabGroup.Visible = 'off';
            app.CallingApp  = mainapp;
            app.EigenvaluePlotPanel.Position = [1 1  450 350];
            app.UIAxes.Position              = [10 10 400 310];
            app.ModelDisplayArea.Visible     = 'off';
            app.ConfidenceIntervalsTab.Parent = [];
            app.ShowTabGroup();
            app.ResizeWindow();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ErrorVariancesTab = uitab(app.TabGroup);
            app.ErrorVariancesTab.Title = 'Error Variances';
            app.UITable = uitable(app.ErrorVariancesTab);
            app.UITable.ColumnName = {'Variable'; 'Error Variance'};
            app.UITable.RowName = {};
            app.ModelTab = uitab(app.TabGroup);
            app.ModelTab.Title = 'Model';
            app.UITable2 = uitable(app.ModelTab);
            app.UITable2.ColumnName = {'Variable'; 'Coefficient'};
            app.UITable2.RowName = {};
            app.ModelDisplayArea = uitextarea(app.ModelTab);
            app.ConfidenceIntervalsTab = uitab(app.TabGroup);
            app.ConfidenceIntervalsTab.Title = 'Confidence Intervals';
            app.UITable3 = uitable(app.ConfidenceIntervalsTab);
            app.UITable3.ColumnName = {'Variable'; 'Interval'};
            app.UITable3.RowName = {};
            app.Image = uiimage(app.ConfidenceIntervalsTab);
            app.Image.ImageSource = 'Insignificant_legend.png';
            app.Image2 = uiimage(app.ConfidenceIntervalsTab);
            app.Image2.ImageSource = 'Significant_legend.png';
            app.EigenvaluePlotPanel = uipanel(app.UIFigure);
            app.EigenvaluePlotPanel.Title = 'Eigenvalue Plot';
            app.UIAxes = uiaxes(app.EigenvaluePlotPanel);
            title(app.UIAxes, 'Plot of Log of Eigenvalues')
            xlabel(app.UIAxes, 'Eigenvalue index --->')
            ylabel(app.UIAxes, 'Log(eigenvalue)--->')
            app.UIAxes.XTick = [0 2 4 6 8 10 12 14 16 18 20 22 24 26 28 30];
            app.UIAxes.XTickLabel = {'0'; '2'; '4'; '6'; '8'; '10'; '12'; '14'; '16'; '18'; '20'; '22'; '24'; '26'; '28'; '30'};
            app.UIAxes.YTick = [0 1 2 3 4];
            app.UIAxes.YTickLabel = {'0'; '1'; '2'; '3'; '4'};
            app.FileLabel_2 = uilabel(app.EigenvaluePlotPanel);
            app.FileLabel_2.Text = '';
            app.FileLabel_3 = uilabel(app.EigenvaluePlotPanel);
            app.FileLabel_3.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OutputApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end