classdef Pre_process_pop_up < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Image_3                 matlab.ui.control.Image
        TextArea                matlab.ui.control.TextArea
        Image_2                 matlab.ui.control.Image
        DropDown                matlab.ui.control.DropDown
        AddoperationButton      matlab.ui.control.Button
        TextArea_2              matlab.ui.control.TextArea
        ClearAllButton          matlab.ui.control.Button
        ImportDataButton        matlab.ui.control.Button
        PerformoperationButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        current_operation  % Description
        join_str = " --> "
        ops_order_array = []
    end
    
    methods (Access = public)
        
        function add_button_state(app)
            if isempty(app.TextArea.Value{1})
                app.AddoperationButton.Enable = false;
            else
                app.AddoperationButton.Enable = true;
            end
        end
      
        function ops_num = get_operation_number(app)
            
            if strcmp(app.TextArea.Value{1}, app.DropDown.Items{2})
                ops_num = PreProcessingEnums.MEAN_CENTERING;
            elseif strcmp(app.TextArea.Value{1}, app.DropDown.Items{3})
                ops_num = PreProcessingEnums.STANDARD_RESCALE;
            elseif strcmp(app.TextArea.Value{1}, app.DropDown.Items{4})
                ops_num = PreProcessingEnums.NAN_REMOVAL;
            elseif strcmp(app.TextArea.Value{1}, app.DropDown.Items{5})
                ops_num = PreProcessingEnums.LINEAR_NAN_INTERP;
            elseif strcmp(app.TextArea.Value{1}, app.DropDown.Items{6})
                ops_num = PreProcessingEnums.ADF_STATIONARITY;
            elseif strcmp(app.TextArea.Value{1}, app.DropDown.Items{7})
                ops_num = PreProcessingEnums.KPSS_STATIONARITY;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disp(class(app.TextArea.Value))
            app.add_button_state();
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            app.TextArea.Value{1} = value;
             app.add_button_state();
        end

        % Button pushed function: AddoperationButton
        function AddoperationButtonPushed(app, event)
            if isempty(app.current_operation)
                app.current_operation = app.TextArea.Value{1};
            else
                app.current_operation = app.current_operation + app.join_str + app.TextArea.Value{1};
            end
            
            app.TextArea_2.Value = app.current_operation;
            app.ops_order_array = [app.ops_order_array app.get_operation_number];
            
            app.TextArea.Value = {''};
            app.add_button_state();
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            app.TextArea_2.Value{1} = '';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Select Operation', 'Mean centering', 'Standardize / Rescale', 'Remove Nan', 'Linear Nan interpolation', 'ADF stationarity test', 'KPSS stationarity test'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Select Operation';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = 'Arrow_right_2a.png';
            app.Image_3 = uiimage(app.UIFigure);
            app.Image_3.ImageSource = 'Arrow_right_2a.png';
            app.TextArea = uitextarea(app.UIFigure);
            app.AddoperationButton = uibutton(app.UIFigure, 'push');
            app.AddoperationButton.ButtonPushedFcn = createCallbackFcn(app, @AddoperationButtonPushed, true);
            app.AddoperationButton.Text = 'Add operation';
            app.ClearAllButton = uibutton(app.UIFigure, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Tooltip = {'The ordder of pre-processing operations'};
            app.ImportDataButton = uibutton(app.UIFigure, 'push');
            app.ImportDataButton.Text = 'Import Data';
            app.PerformoperationButton = uibutton(app.UIFigure, 'push');
            app.PerformoperationButton.Text = 'Perform operation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Pre_process_pop_up

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end