classdef SaveModel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        VariableEditFieldLabel       matlab.ui.control.Label
        VariableEditField            matlab.ui.control.EditField
        IODelayEditFieldLabel        matlab.ui.control.Label
        IODelayEditField             matlab.ui.control.EditField
        NotesTextAreaLabel           matlab.ui.control.Label
        NotesTextArea                matlab.ui.control.TextArea
        ModelNameEditFieldLabel      matlab.ui.control.Label
        ModelNameEditField           matlab.ui.control.EditField
        TimeUnitsEditFieldLabel      matlab.ui.control.Label
        TimeUnitsEditField           matlab.ui.control.EditField
        DoneButton                   matlab.ui.control.Button
        ExporttoWorkspaceButton      matlab.ui.control.Button
        NumeratorEditFieldLabel      matlab.ui.control.Label
        NumeratorEditField           matlab.ui.control.EditField
        DenominatorEditFieldLabel    matlab.ui.control.Label
        DenominatorEditField         matlab.ui.control.EditField
        NoiseVarianceEditFieldLabel  matlab.ui.control.Label
        NoiseVarianceEditField       matlab.ui.control.EditField
        TimeStepEditFieldLabel       matlab.ui.control.Label
        TimeStepEditField            matlab.ui.control.EditField
    end

    
    properties 
        Numerator
        Denominator
        Variable = 's';
        IODelay = 0;
        NoiseVariance = [];
        Report
        TimeStep
        TimeUnit = 'seconds';
        UserData = [];
        Name
        
        model
    end
    
    methods
        
        function UIState(app)
            
            app.NumeratorEditField.Value     = num2str(app.Numerator) ;
            app.DenominatorEditField.Value   = num2str(app.Denominator);
            app.VariableEditField.Value      = app.Variable;
            app.IODelayEditField.Value       = num2str(app.IODelay); 
            app.NoiseVarianceEditField.Value = num2str(app.NoiseVariance');
            app.NotesTextArea.Value          = [cellstr(app.Report(1));cellstr(app.Report(2));cellstr(app.Report(3))];
            app.TimeStepEditField.Value      = num2str(app.TimeStep);
            app.TimeUnitsEditField.Value     = app.TimeUnit;
            app.ModelNameEditField.Value     = app.Name;
            
        
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ModelObject)
            
            app.Numerator           = ModelObject.Numerator;
            app.Denominator         = ModelObject.Denominator;
            app.Variable            = ModelObject.Variable;
            app.IODelay             = ModelObject.IODelay;
            app.NoiseVariance       = ModelObject.NoiseVariance;
            app.Report              = ModelObject.Report;
            app.TimeStep            = ModelObject.Ts;
            app.TimeUnit            = ModelObject.TimeUnit;
            app.UserData            = ModelObject.UserData;
            app.Name                = ModelObject.Name;
            
            app.UIState();
            
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            num = str2num(app.NumeratorEditField.Value);    
            dem = str2num(app.DenominatorEditField.Value); 
            num_size = size(num);
            den_size = size(dem);
                    
            if(num_size(2)~=den_size(2))
                warndlg('The model numerator and denominator must be of the same dimensions.')
                return;
            end
            
            var = app.VariableEditField.Value;      
            IODel = str2num(app.IODelayEditField.Value);
            
            noiseVar = str2num(app.NoiseVarianceEditField.Value)'; 
            report = app.NotesTextArea.Value;
             
            Ts     = str2num(app.TimeStepEditField.Value);      
            timeunit = app.TimeUnitsEditField.Value;     
            name = app.ModelNameEditField.Value;  
            
            app.model = CrossValidUtils.m_createXPCAObject(num,dem,var,IODel,noiseVar,report,Ts,timeunit,'XPCA',name);
            model1 = app.model;
            save(name, 'model1')
          
        end

        % Button pushed function: ExporttoWorkspaceButton
        function ExporttoWorkspaceButtonPushed(app, event)
            num = str2num(app.NumeratorEditField.Value);    
            dem = str2num(app.DenominatorEditField.Value); 
            num_size = size(num);
            den_size = size(dem);
                    
            if(num_size(2)~=den_size(2))
                warndlg('The model numerator and denominator must be of the same dimensions.')
                return;
            end
            
            var = app.VariableEditField.Value;      
            IODel = str2num(app.IODelayEditField.Value);
            
            noiseVar = str2num(app.NoiseVarianceEditField.Value)'; 
            report = app.NotesTextArea.Value;
            
            Ts     = str2num(app.TimeStepEditField.Value);      
            timeunit = app.TimeUnitsEditField.Value;     
            name = app.ModelNameEditField.Value;  
            
            app.model = CrossValidUtils.m_createXPCAObject(num,dem,var,IODel,noiseVar,report,Ts,timeunit,'XPCA',name);
            model1 = app.model;
            assignin("base","CurrentSessionModel",model1)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.VariableEditFieldLabel = uilabel(app.UIFigure);
            app.VariableEditFieldLabel.Text = 'Variable';
            app.VariableEditField = uieditfield(app.UIFigure, 'text');
            app.VariableEditField.Value = 'q^-1';
            app.IODelayEditFieldLabel = uilabel(app.UIFigure);
            app.IODelayEditFieldLabel.Text = 'IODelay';
            app.IODelayEditField = uieditfield(app.UIFigure, 'text');
            app.IODelayEditField.Editable = 'off';
            app.NotesTextAreaLabel = uilabel(app.UIFigure);
            app.NotesTextAreaLabel.Text = ' Notes';
            app.NotesTextArea = uitextarea(app.UIFigure);
            app.NotesTextArea.Editable = 'off';
            app.ModelNameEditFieldLabel = uilabel(app.UIFigure);
            app.ModelNameEditFieldLabel.Text = 'Model Name';
            app.ModelNameEditField = uieditfield(app.UIFigure, 'text');
            app.TimeUnitsEditFieldLabel = uilabel(app.UIFigure);
            app.TimeUnitsEditFieldLabel.Text = 'Time Units';
            app.TimeUnitsEditField = uieditfield(app.UIFigure, 'text');
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.ExporttoWorkspaceButton = uibutton(app.UIFigure, 'push');
            app.ExporttoWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoWorkspaceButtonPushed, true);
            app.ExporttoWorkspaceButton.Text = 'Export to Workspace';
            app.NumeratorEditFieldLabel = uilabel(app.UIFigure);
            app.NumeratorEditFieldLabel.Text = 'Numerator';
            app.NumeratorEditField = uieditfield(app.UIFigure, 'text');
            app.NumeratorEditField.Editable = 'off';
            app.DenominatorEditFieldLabel = uilabel(app.UIFigure);
            app.DenominatorEditFieldLabel.Text = 'Denominator';
            app.DenominatorEditField = uieditfield(app.UIFigure, 'text');
            app.DenominatorEditField.Editable = 'off';
            app.NoiseVarianceEditFieldLabel = uilabel(app.UIFigure);
            app.NoiseVarianceEditFieldLabel.Text = 'Noise Variance';
            app.NoiseVarianceEditField = uieditfield(app.UIFigure, 'text');
            app.NoiseVarianceEditField.Editable = 'off';
            app.TimeStepEditFieldLabel = uilabel(app.UIFigure);
            app.TimeStepEditFieldLabel.Text = 'Time Step';
            app.TimeStepEditField = uieditfield(app.UIFigure, 'text');
            app.TimeStepEditField.Editable = 'off';
            app.TimeStepEditField.Value = '1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SaveModel(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end