classdef SaveModelStatic < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ModelNameEditFieldLabel      matlab.ui.control.Label
        ModelNameEditField           matlab.ui.control.EditField
        NoiseVarianceEditFieldLabel  matlab.ui.control.Label
        NoiseVarianceEditField       matlab.ui.control.EditField
        NotesTextAreaLabel           matlab.ui.control.Label
        NotesTextArea                matlab.ui.control.TextArea
        UITable                      matlab.ui.control.Table
        DoneButton                   matlab.ui.control.Button
        ExporttoWorkspaceButton      matlab.ui.control.Button
    end

    
    properties 
        RegressionModel
        NoiseVariance = [];
        Report
        UserData = [];
        Name
        
        model
    end
    
    methods
        
        function UIState(app)
            
            no_of_Output_stat = size(app.RegressionModel,1);
            no_of_Input_stat  = size(app.RegressionModel,2);
            
            t1 = 1:no_of_Input_stat;
            t2 = 1:no_of_Output_stat;
                            
            columnnames    = "Input " + t1;
            rownames       = "Output "+ t2;
            
            
            app.UITable.RowName    = rownames;
            
            app.UITable.ColumnName = columnnames;
            
            app.UITable.Data       = app.RegressionModel;
             
            app.NoiseVarianceEditField.Value = num2str(app.NoiseVariance');
            app.NotesTextArea.Value          = [cellstr(app.Report(1));cellstr(app.Report(2));cellstr(app.Report(3))];
            app.ModelNameEditField.Value     = app.Name;
            
        
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ModelObject)
            app.RegressionModel     = ModelObject.RegressionModel;
            app.NoiseVariance       = ModelObject.NoiseVariance;
            app.Report              = ModelObject.Report;
            app.UserData            = ModelObject.UserData;
            app.Name                = ModelObject.Name;
            
            app.UIState();
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            RegressionPoly = app.RegressionModel;
            noiseVar = str2num(app.NoiseVarianceEditField.Value)'; 
            report = app.NotesTextArea.Value;
            name = app.ModelNameEditField.Value;  
            
            app.model = CrossValidUtils.m_createXPCAStaticObject(RegressionPoly,noiseVar,report,'XPCA',name);
            model1 = app.model;
            save(name, 'model1')
        end

        % Button pushed function: ExporttoWorkspaceButton
        function ExporttoWorkspaceButtonPushed(app, event)
            RegressionPoly = app.RegressionModel;
            noiseVar = str2num(app.NoiseVarianceEditField.Value)'; 
            report = app.NotesTextArea.Value;
            name = app.ModelNameEditField.Value;  
            
            app.model = CrossValidUtils.m_createXPCAStaticObject(RegressionPoly,noiseVar,report,'XPCA',name);
            model1 = app.model;
            assignin("base","CurrentSessionModel",model1)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ModelNameEditFieldLabel = uilabel(app.UIFigure);
            app.ModelNameEditFieldLabel.Text = 'Model Name';
            app.ModelNameEditField = uieditfield(app.UIFigure, 'text');
            app.NoiseVarianceEditFieldLabel = uilabel(app.UIFigure);
            app.NoiseVarianceEditFieldLabel.Text = 'Noise Variance';
            app.NoiseVarianceEditField = uieditfield(app.UIFigure, 'text');
            app.NoiseVarianceEditField.Editable = 'off';
            app.NotesTextAreaLabel = uilabel(app.UIFigure);
            app.NotesTextAreaLabel.Text = ' Notes';
            app.NotesTextArea = uitextarea(app.UIFigure);
            app.NotesTextArea.Editable = 'off';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.ExporttoWorkspaceButton = uibutton(app.UIFigure, 'push');
            app.ExporttoWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoWorkspaceButtonPushed, true);
            app.ExporttoWorkspaceButton.Text = 'Export to Workspace';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SaveModelStatic(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end