classdef WorkSpaceApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        WorkspacevariableLabel  matlab.ui.control.Label
        EditField               matlab.ui.control.EditField
        DoneButton              matlab.ui.control.Button
        CancelButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        varName % Description
        CallingApp
        defaultVarName
        data
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.CallingApp = mainApp;
            %app.CallingApp     ----> calls Cross_validation app
            app.defaultVarName = app.EditField.Value;
            app.UIFigure.Position = [375 650 246 138];
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.varName = app.EditField.Value;
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            try
                
              app.data = evalin('base',app.varName);
              %app.data   -----> works for cross valid
              if(~isempty(app.data))
                app.CallingApp.GetWorkspaceVariable(app.varName);
                close(app.UIFigure)
              else
                  f = warndlg('Variable is empty!Try again with a different variable','Warning');
                  app.EditField.Value = app.defaultVarName;
                  return;
              end
            catch ME
                switch ME.identifier
                    case 'MATLAB:UndefinedFunction'
                        f = warndlg('No such data variable in the base workspace! Try again with a different variable','Warning');
                        app.EditField.Value = app.defaultVarName;
                        return;
                end
            end
            close(app.UIFigure)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.CallingApp.CancelWorkSpace();
            close(app.UIFigure)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.WorkspacevariableLabel = uilabel(app.UIFigure);
            app.WorkspacevariableLabel.Text = 'Workspace variable ';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WorkSpaceApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end