classdef XPCA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        XPCAPanel                       matlab.ui.container.Panel
        ModelConfigurationPanel         matlab.ui.container.Panel
        ModelTypeButtonGroup            matlab.ui.container.ButtonGroup
        StaticButton                    matlab.ui.control.RadioButton
        DynamicButton                   matlab.ui.control.RadioButton
        NoiseVariancesButtonGroup_2     matlab.ui.container.ButtonGroup
        KnownButton                     matlab.ui.control.RadioButton
        UnknownButton                   matlab.ui.control.RadioButton
        NoiseModelButtonGroup           matlab.ui.container.ButtonGroup
        NoiselessButton                 matlab.ui.control.RadioButton
        NoisyButton                     matlab.ui.control.RadioButton
        ModelOrderButtonGroup           matlab.ui.container.ButtonGroup
        KnownButton_2                   matlab.ui.control.RadioButton
        UnknownButton_2                 matlab.ui.control.RadioButton
        LagEditFieldLabel               matlab.ui.control.Label
        LagEditField                    matlab.ui.control.NumericEditField
        InputcolEditFieldLabel          matlab.ui.control.Label
        InputcolEditField               matlab.ui.control.EditField
        OutputcolEditFieldLabel         matlab.ui.control.Label
        OutputcolEditField              matlab.ui.control.EditField
        TextArea                        matlab.ui.control.TextArea
        ModelEstimationPanel            matlab.ui.container.Panel
        BuildModelButton                matlab.ui.control.Button
        ModelValidationPanel            matlab.ui.container.Panel
        ComputeConfidenceIntervalsButton  matlab.ui.control.Button
        ComputeResidualsButton          matlab.ui.control.Button
        AdvOptionsButton                matlab.ui.control.Button
        SaveModelButton                 matlab.ui.control.Button
        DataProcessingPanel_2           matlab.ui.container.Panel
        LoadDataDropDownLabel           matlab.ui.control.Label
        LoadDataDropDown                matlab.ui.control.DropDown
        FileLabel                       matlab.ui.control.Label
        LoadsessionButton               matlab.ui.control.Button
        FileLabel_2                     matlab.ui.control.Label
        ClearsessionButton              matlab.ui.control.Button
        AdvancedOptionsDataProcessingPanel  matlab.ui.container.Panel
        PreProcessDataButton            matlab.ui.control.Button
        FileLabel_3                     matlab.ui.control.Label
        AdvancedOptionsModelConfigurationPanel  matlab.ui.container.Panel
        UITable                         matlab.ui.control.Table
        DoneButton                      matlab.ui.control.Button
        EnterOrderEditFieldLabel        matlab.ui.control.Label
        EnterOrderEditField             matlab.ui.control.NumericEditField
        AdvancedOptionsModelEstimationPanel  matlab.ui.container.Panel
        ModelOrderEstLabel              matlab.ui.control.Label
        ModelOrderEstDropDown           matlab.ui.control.DropDown
        VarianceEstDropDownLabel        matlab.ui.control.Label
        VarianceEstDropDown             matlab.ui.control.DropDown
        AdvancedOptionsModelValidationPanel  matlab.ui.container.Panel
        SizeofBootstrapsEditFieldLabel  matlab.ui.control.Label
        SizeofBootstrapsEditField       matlab.ui.control.NumericEditField
        NoofBootstrapsEditFieldLabel    matlab.ui.control.Label
        NoofBootstrapsEditField         matlab.ui.control.NumericEditField
        CrossValidateButton             matlab.ui.control.Button
        ModelComparisonButton           matlab.ui.control.Button
        CISignificanceEditFieldLabel    matlab.ui.control.Label
        CISignificanceEditField         matlab.ui.control.NumericEditField
        BootstrapSizeinEditFieldLabel   matlab.ui.control.Label
        BootstrapSizeinEditField        matlab.ui.control.NumericEditField
        Image                           matlab.ui.control.Image
        Image_2                         matlab.ui.control.Image
        Image_3                         matlab.ui.control.Image
        Image_4                         matlab.ui.control.Image
        Image_5                         matlab.ui.control.Image
        Image_6                         matlab.ui.control.Image
    end

    
    properties (Access = private)
        STATIC_TYPE = 1;
        DYNAMIC_TYPE = 2;
        
        % Basic input data variables
        data;
        ModelType;
        NoOfInputsAndOutputs; %This is an App
        NoOfInputs;
        NoOfOutputs;
        InputColumns;
        OutputColumns;
        Lag;
        crossData = [];
        crossData_Label;
        
        % These flags are helpful in enabling/disabling features in UI
        IsFileLoaded            = false;
        IsNoiseless             = false;
        IsknownError            = false;
        IsStatic                = true;
        IsknownOrder            = false;
        IsModelOrderComputed    = false;
        IsNoiseVarianceComputed = false;
        IsModelComputed         = false;
        
        % Constructing these objects to invoke the backend code
        ModelObj;
        StaticObj ;
        DynamicObj;
        VarianceModelObj;
        OrderCalculatorObj;
        VarianceObj;
        
        XPCAObj;   % THE MAIN OBJECT TO WORK WITH BACKEND
        
        % Outputs from the Estimation algorithms
        Ordervalue = 0;
        Order = 0;
        variance_model;
        NoiseVariances;
        Eigenvalues;
        Model;
        
        FAILED = 0;
        SUCCESS = 1;
        
        % The Order value input App 
        OrderInputApp;
        
        % The output App to render the output data on UI
        OutApp;
        CrossApp;
        WorkSpaceApp;
        AdvancedOptions = false;
        resid_fig;
        
        NumberOfBootstraps = 100;
        SizeOfBootstraps   = 400;
        BootstrapSizePercent = 80;
        AlphaValue         = 5;
        ConfidenceIntervals;
        Residuals;   %new
        
        DoneButtonPushedState = 0;
        BuildModelButtonClickedState = 0;
        ComputeConfidenceIntervalsButtonPushedState = 0;
        ComputeResidualsButtonPushedState = 0;
        CrossValidateButtonPushedState = 0;   %Cross Validate Button in XPCA App
        
        CrossValidAppButtonPushedState = 0;   %Cross Validate Button in Cross_validation App
        ModelComparisonButtonPushedState = 0;
        
        IsLoadedState = 0;
        
    end
    
    properties (Access = private)
        LabelsToSave  = {'LoadDataDropDownLabel','FileLabel','FileLabel_2'}
        ValuesToSave  = {'LoadDataDropDown','NoiselessButton','NoisyButton','KnownButton','UnknownButton','StaticButton','DynamicButton','KnownButton_2','UnknownButton_2','LagEditField','InputcolEditField','OutputcolEditField','EnterOrderEditField','SizeofBootstrapsEditField','NoofBootstrapsEditField','CISignificanceEditField','BootstrapSizeinEditField'}
        DataToSave    = {'UITable'}
        PropsToSave   = {'data','NoiseVariances','InputColumns','OutputColumns','NumberOfBootstraps','SizeOfBootstraps','BootstrapSizePercent','AlphaValue','IsFileLoaded','IsNoiseless','IsknownError','IsStatic','IsknownOrder','IsModelOrderComputed','IsNoiseVarianceComputed','IsModelComputed','CrossValidAppButtonPushedState','crossData','crossData_Label'}
        ButtonPressed = {'DoneButtonPushed','BuildModelButtonClicked','ComputeConfidenceIntervalsButtonPushed','ComputeResidualsButtonPushed','CrossValidateButtonPushed'} %,'ComputeConfidenceIntervalsButtonPushed','ComputeResidualsButtonPushed'
    end
    
    methods (Access = public)
        
        function saveState(app)
            state = struct();
                        
            %app.SizeOfBootstraps
            % save labels
            for i = 1:length(app.LabelsToSave)
                state.(app.LabelsToSave{i}).Text = app.(app.LabelsToSave{i}).Text;
            end
            % save values
            for i = 1:length(app.ValuesToSave)
                state.(app.ValuesToSave{i}).Value = app.(app.ValuesToSave{i}).Value;
            end
            
            %app.UITable.Data
            
            for i = 1:length(app.DataToSave)
                state.(app.DataToSave{i}).Data = app.(app.DataToSave{i}).Data;
            end
            
            %app.CrossApp = app.GetCrossValidationApp();
            if(exist('app.CrossApp'))
                if(app.CrossValidateButtonPushedState == 1)
                    %app.CrossApp
                    [app.crossData, app.crossData_Label, app.CrossValidAppButtonPushedState] = GetCrossDataAndState(app.CrossApp);
                end
            else
                app.CrossValidateButtonPushedState = 0;
                app.CrossValidAppButtonPushedState = 0;
                app.crossData = [];
                app.crossData_Label = '';
            end
            
            %save properties 
            
            for i = 1:length(app.PropsToSave)
                state.(app.PropsToSave{i}) = app.(app.PropsToSave{i});
            end
            
            for i = 1:length(app.ButtonPressed)
                state.(app.ButtonPressed{i}) = app.(strcat(app.ButtonPressed{i},'State'));
            end
            
%             state.data = app.data;
%             state.crossData = app.crossData; 
            
            prompt = {'Enter variable name:'};
            dlgtitle = 'Save variable as';
            dims = [1 35];
            definput = {'state'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
%            save('state.mat','state');
            session=char(answer);
            %session
            sess = strcat(session,'.mat');
            save(sess,'state');
        end
    
        function loadState(app)
            
            [file,~] = uigetfile('*.mat');
            %filename = fullfile(path, file);
            
            if ~exist(file,'file')
                return
            end
            
            state = load(file,'state');
            state = state.state;
            
            app.ReloadUIAndInitializeDataObjects();
            
%             a = app.SizeOfBootstraps;
%             a
            
            % load labels
            app.IsLoadedState = 1;
            for i = 1:length(app.LabelsToSave)
                app.(app.LabelsToSave{i}).Text = state.(app.LabelsToSave{i}).Text;
            end
           
            
            % load values
            for i = 1:length(app.ValuesToSave)
                app.(app.ValuesToSave{i}).Value = state.(app.ValuesToSave{i}).Value;
            end
            
            for i = 1:length(app.PropsToSave)
                app.(app.PropsToSave{i})        = state.(app.PropsToSave{i});
            end
            
            %%% Data must be fed to xpca object
            %app.data  = state.data;
            
            %app.XPCAObj = xpca(app.data);
            %%%
            
            app.LoadKnownVariance();

            for i = 1:length(app.DataToSave)
                app.(app.DataToSave{i}).Data    =  state.(app.DataToSave{i}).Data;
            end
            
            
            app.DataProcessingPanel();
            app.ReloadUIAndInitializeDataObjects();
            app.EnableModelConfigurationPanel();
            app.ConstructModelConfigurationObject();
            app.EnableAdvancedOptionsModelConfigurationPanel();
            app.EnableModelEstimationPanel();
            
            
            if(state.(app.ButtonPressed{2})==0)
                delete(app.OutApp)
            else
                if(state.(app.ButtonPressed{4})==0)
                    delete(app.resid_fig)
                end
            end
            
            
            for i = 1:length(app.ButtonPressed)
                if(state.(app.ButtonPressed{i})==1)
                    funct = str2func(app.ButtonPressed{i});
                    funct(app, matlab.ui.eventdata.ButtonPushedData);
                end
            end
            
        end
        
        function loadClearState(app)
            if ~exist('ClearSession.mat','file')
                return
            end
            
            state = load('ClearSession.mat','state');
            state = state.state;
            
            app.IsFileLoaded            = false;
            app.IsLoadedState           = false;
            app.IsNoiseless             = false;
            app.IsknownError            = false;
            app.IsStatic                = true;
            app.IsknownOrder            = false;
            app.IsModelOrderComputed    = false;
            app.IsNoiseVarianceComputed = false;
            app.IsModelComputed         = false;
            app.Ordervalue              = 0;
            app.Order                   = 0;
            app.DoneButtonPushedState   = 0;
            app.BuildModelButtonClickedState = 0;
            app.ComputeConfidenceIntervalsButtonPushedState = 0;
            app.NumberOfBootstraps      = 100;
            app.SizeOfBootstraps        = 400;
            app.BootstrapSizePercent    = 80;
            app.AlphaValue              = 5;
            app.ComputeResidualsButtonPushedState = 0;
            app.CrossValidateButtonPushedState = 0;
            app.CrossValidAppButtonPushedState = 0;
            app.IsLoadedState          = 0;
            
            app.ReloadUIAndInitializeDataObjects();
            
            for i = 1:length(app.LabelsToSave)
                app.(app.LabelsToSave{i}).Text  = state.(app.LabelsToSave{i}).Text;
            end
            
            for i = 1:length(app.ValuesToSave)
                app.(app.ValuesToSave{i}).Value = state.(app.ValuesToSave{i}).Value;
            end
            
            for i = 1:length(app.PropsToSave)
                app.(app.PropsToSave{i})        = state.(app.PropsToSave{i});
            end
           
            %app.data  = state.data;
            app.XPCAObj = xpca(state.data);
            
            for i = 1:length(app.DataToSave)
                app.(app.DataToSave{i}).Data   =  state.(app.DataToSave{i}).Data;
            end
            
            app.DataProcessingPanel();
            app.ReloadUIAndInitializeDataObjects();
            app.EnableModelConfigurationPanel();
            app.ConstructModelConfigurationObject();
            app.EnableAdvancedOptionsModelConfigurationPanel();
            %app.EnableModelEstimationPanel();
            %here advancedestimation enable
            %app.EnableModelValidationPanel();
            app.EnableAdvancedOptionsModelValidationPanel();
                
            
        end


        function UpdateOrdervalue(app, order_value)
            app.Ordervalue = order_value;
        end
        
        function total_vars = GetTotalNumberofVariables(app)
            total_vars = app.XPCAObj.m_getTotalNumberOfVariables();
        end
        
        % Not using now -- will be deprecated
        function UpdateNoOfInputsAndOutputs(app, no_of_inputs, no_of_outputs)
            
            app.NoOfInputs  = no_of_inputs;
            app.NoOfOutputs = no_of_outputs;
%             app.NoOfInputs
%             app.NoOfOutputs
        end
        
        function GetWorkspaceVariable(app, varName)
            
            app.data = evalin('base',varName);
            app.IsFileLoaded = true;
            %app.XPCAObj = xpca(app.data);
            app.LoadDataDropDown.Enable = 'off';
            app.FileLabel.Text = "Variable Loaded: " + varName;
            app.FileLabel.Tooltip = app.FileLabel.Text;
            app.EnableModelConfigurationPanel();
            app.ReloadUIAndInitializeDataObjects();
            app.RefreshModelEstimationPanel();
            
        end
        
        function CancelWorkSpace(app)
            app.LoadDataDropDown.Value = "Select Input Type";
        end
        
    end
    
    methods (Access = private)
        
        %% Functions concerning UI conditions are in this block
        
        function DataProcessingPanel(app)
            if(app.IsFileLoaded == false)
                 app.LoadDataDropDown.Enable = true;
            else
                 app.LoadDataDropDown.Enable = false;
            end
        end
        
        function ReloadUIAndInitializeDataObjects(app)
            
            if (app.StaticButton.Value==1)
                app.ConfigureUIAndCreateObjectForStaticCase();
            else
                app.ConfigureUIAndCreateObjectForDynamicCase();
            end
          
        end
        
        %% Functions to configure the UI in Static and Dynamic Case
        function ConfigureUIAndCreateObjectForStaticCase(app)
            
            app.DisableModelEstimationPanel();
            app.DisableModelValidationPanel();
            
            app.LagEditField.Visible                = 'off';
            app.LagEditFieldLabel.Visible           = 'off';
            app.InputcolEditField.Visible           = 'on';
            app.InputcolEditFieldLabel.Visible      = 'on';
            app.OutputcolEditField.Visible          = 'on';
            app.OutputcolEditFieldLabel.Visible     = 'on';
            app.TextArea.Visible                    = 'on';
            app.ModelOrderEstDropDown.Enable        = 'off';
            app.ModelOrderEstLabel.Enable           = 'off';
            
            app.ConstructStaticObject();
            
            app.IsModelOrderComputed                = false;
            if (app.NoiselessButton.Value==1)
%                 if(app.UnknownButton.Value==1)
%                     app.IsNoiseVarianceComputed     = false;
%                 else
%                     app.IsNoiseVarianceComputed     = true;
%                 end
%             else
                app.IsNoiseVarianceComputed             = true;
            end
  
            app.RefreshModelEstimationPanel();
        end
        
        function ConfigureUIAndCreateObjectForDynamicCase(app)
            
            app.DisableModelEstimationPanel();
            app.DisableModelValidationPanel();
            
            app.LagEditField.Visible                = 'on';
            app.LagEditFieldLabel.Visible           = 'on';
            app.InputcolEditField.Visible           = 'on';
            app.InputcolEditFieldLabel.Visible      = 'on';
            app.OutputcolEditField.Visible          = 'on';
            app.OutputcolEditFieldLabel.Visible     = 'on';
            app.TextArea.Visible                    = 'on';
            
            %app.EstimateModelOrderButton.Visible    = 'on';
            app.ModelOrderEstDropDown.Visible       = 'on';
            app.ModelOrderEstLabel.Visible          = 'on';
            
            app.ConstructDynamicObject();
            
            app.IsModelOrderComputed                = false;
            
            if (app.NoiselessButton.Value == 1)
                
                app.IsNoiseVarianceComputed         = true;
            end
            
            app.RefreshModelEstimationPanel();
        end
        
        % Functions to disable the freeze the UI
        function DisableModelConfigurationPanel(app)
            
            app.NoiselessButton.Enable              = 'off';
            app.NoisyButton.Enable                  = 'off';
            app.StaticButton.Enable                 = 'off';
            app.DynamicButton.Enable                = 'off';
            app.KnownButton.Enable                  = 'off';
            app.UnknownButton.Enable                = 'off';
            app.LagEditField.Enable                 = 'off';
            app.KnownButton_2.Enable                = 'off';
            app.UnknownButton_2.Enable              = 'off';
            app.InputcolEditField.Enable            = 'off';
            app.InputcolEditFieldLabel.Enable       = 'off';
            app.OutputcolEditField.Enable           = 'off';
            app.OutputcolEditFieldLabel.Enable      = 'off';
            app.TextArea.Enable                     = 'off';
            app.UITable.Enable                      = 'off';
            app.EnterOrderEditField.Enable          = 'off';
            app.DoneButton.Enable                   = 'off';
        end
        
        function DisableModelEstimationPanel(app)
            
            %app.EstimateModelOrderButton.Enable     = 'off';
            %app.EstimateNoiseVariancesButton.Enable = 'off';
            app.BuildModelButton.Enable             = 'off';
            %app.ModelOrderEstDropDown.Enable        = 'off';
            %app.VarianceEstDropDown.Enable          = 'off';
        end
        
        function DisableModelValidationPanel(app)
            
            app.ComputeConfidenceIntervalsButton.Enable     = 'off';
            app.ComputeResidualsButton.Enable               = 'off';
            
        end
        
        %% Functions to handle the visibility of Advanced Options
        function HideAdvancedOptions(app)
            
            app.AdvOptionsButton.Text                           = "Adv. Options";
            app.UIFigure.Position                               = [100 100 260 700];
            app.AdvancedOptionsDataProcessingPanel.Visible      = 'off';
            app.AdvancedOptionsModelConfigurationPanel.Visible  = 'off';
            app.AdvancedOptionsModelEstimationPanel.Visible     = 'off';
            app.AdvancedOptionsModelValidationPanel.Visible     = 'off';
        end
        
        function ShowAdvancedOptions(app)
            
            app.AdvOptionsButton.Text                           = "Hide Adv. Options";
            app.UIFigure.Position                               = [100 100 580 700];
            app.AdvancedOptionsDataProcessingPanel.Visible      = 'on';
            app.AdvancedOptionsModelConfigurationPanel.Visible  = 'on';
            app.AdvancedOptionsModelEstimationPanel.Visible     = 'on';
            app.AdvancedOptionsModelValidationPanel.Visible     = 'on';
        end
        
        
        %% Functions to Enable UI features
        
        %%function below should not be used in or after Build Model button
        %%V V IMP
        function EnableModelConfigurationPanel(app)
            
            if(app.IsFileLoaded == 1)
                app.NoiselessButton.Enable      = 'on';
                app.NoisyButton.Enable          = 'on';
                app.StaticButton.Enable         = 'on';
                app.DynamicButton.Enable        = 'on';
                app.LagEditField.Enable         = 'on';
                app.UnknownButton_2.Enable      = 'on';
                app.KnownButton_2.Enable        = 'on';
                app.InputcolEditField.Enable           = 'on';
                app.InputcolEditFieldLabel.Enable      = 'on';
                app.OutputcolEditField.Enable          = 'on';
                app.OutputcolEditFieldLabel.Enable     = 'on';
                app.TextArea.Enable                    = 'on';
                
                if (app.NoiselessButton.Value == 0)
                    app.KnownButton.Enable      = 'on';
                    app.UnknownButton.Enable    = 'on';
                else
                    app.KnownButton.Enable      = 'off';
                    app.UnknownButton.Enable    = 'off';
                end
                
                if(app.KnownButton.Value == 1)
                    app.UITable.Enable          = 'on';
                    %app.ShowAdvancedOptions();
                else
                    app.UITable.Enable          = 'off';
                    %app.HideAdvancedOptions();
                end
                
                if(app.KnownButton_2.Value == 1)
                    app.EnterOrderEditField.Enable = 'on';
                    app.EnterOrderEditFieldLabel.Enable = "on";
                    app.LagEditField.Visible       = 'off';
                    app.LagEditFieldLabel.Visible  = 'off';
                    %app.ShowAdvancedOptions();
                else
                    app.EnterOrderEditField.Enable = 'off';
                    %app.EnterOrderEditFieldLabel.Enable = "off";
                    %app.HideAdvancedOptions();
                end
                
                if(app.DynamicButton.Value == 1)
                    app.LagEditField.Enable                = 'on';
                    
                elseif(app.StaticButton.Value == 1)
                    app.LagEditField.Visible               = 'off';
                    app.LagEditFieldLabel.Visible          = 'off';
                    app.KnownButton_2.Enable               = 'off';
                    app.UnknownButton_2.Enable             = 'off';
                end
            else
                app.NoiselessButton.Enable      = 'off';
                app.NoisyButton.Enable          = 'off';
                app.StaticButton.Enable         = 'off';
                app.DynamicButton.Enable        = 'off';
                app.LagEditField.Enable         = 'off';
                app.UnknownButton_2.Enable      = 'off';
                app.KnownButton_2.Enable        = 'off';
                app.KnownButton.Enable          = 'off';
                app.UnknownButton.Enable        = 'off';
                app.InputcolEditField.Enable           = 'off';
                app.InputcolEditFieldLabel.Enable      = 'off';
                app.OutputcolEditField.Enable          = 'off';
                app.OutputcolEditFieldLabel.Enable     = 'off';
                app.TextArea.Enable                    = 'off';
            end
                
        end
        
        function RefreshModelConfigurationPanel(app)
            if(app.IsFileLoaded == 1)
                app.EnableModelConfigurationPanel();
            end
        end
        
        function EnableModelEstimationPanel(app)
            
            if(app.IsFileLoaded == 1)
                app.BuildModelButton.Enable = 'on';
            end
            
        end
        
        function RefreshModelEstimationPanel(app)
            app.EnableModelEstimationPanel();
        end

        
        function EnableModelValidationPanel(app)
            
                if (app.NoiselessButton.Value==0)
                    app.ComputeConfidenceIntervalsButton.Enable = 'on';
                    app.ComputeResidualsButton.Enable           = 'on';
                else
                    app.ComputeConfidenceIntervalsButton.Enable = 'off';
                    app.ComputeResidualsButton.Enable           = 'off';
                end
            
            if (app.IsStatic==1)
                %disp("These setting to be enabled later")
%                app.NoofBootstrapsEditField.Enable          = 'on';
%                app.SizeofBootstrapsEditField.Enable        = 'on';
                app.ComputeConfidenceIntervalsButton.Enable = 'on';
                app.ComputeResidualsButton.Enable           = 'off';
                
                if (app.NoiselessButton.Value==1)
                    app.ComputeConfidenceIntervalsButton.Enable = 'off';
                end
            end
        end
        
        function RefreshModelValidationPanel(app)
            if(app.IsModelComputed == 1)
                app.EnableModelValidationPanel();
            end
        end


        function DisableAdvancedOptionsModelConfigurationPanel(app)
           app.UITable.Enable = "off";
           app.EnterOrderEditField.Enable = "off";
           %app.EnterOrderEditFieldLabel.Enable = "off";
           app.DoneButton.Enable = "off";
           
        end
        
        function EnableAdvancedOptionsModelConfigurationPanel(app)
            if((app.KnownButton.Value==1)||(app.KnownButton_2.Value==1))
                app.UITable.Enable = "on";
                app.DoneButton.Enable = "on";
                app.ShowAdvancedOptions();
            else
                app.UITable.Enable = "off";
                app.DoneButton.Enable = "off";
                app.HideAdvancedOptions();
            end
            
%             if(app.KnownButton_2.Value==1)
%                 app.EnterOrderEditField.Enable = "on";
%                 app.DoneButton.Enable = "on";
%             else
%                 app.EnterOrderEditField.Enable = "off";
%                 app.DoneButton.Enable = "off";
%             end
        end
        
        function RefreshAdvancedOptionsModelConfigurationPanel(app)
            app.EnableAdvancedOptionsModelConfigurationPanel();
        end
        
        function DisableAdvancedOptionsModelEstimationPanel(app)
            app.ModelOrderEstDropDown.Enable = "off";
            app.VarianceEstDropDown.Enable = "off";
        end
        
        function EnableAdvancedOptionsModelEstimationPanel(app)
            
            app.ModelOrderEstDropDown.Enable = "on";
            
            if(app.NoiselessButton.Value == 1 || app.KnownButton.Value == 1)
                app.VarianceEstDropDown.Enable = "off";
            else
                app.VarianceEstDropDown.Enable = "on";
            end
        end
        
        function DisableAdvancedOptionsModelValidationPanel(app)
            app.NoofBootstrapsEditField.Enable              = 'off';
            app.SizeofBootstrapsEditField.Enable            = 'off';
            app.CrossValidateButton.Enable                  = 'off';
            app.ModelComparisonButton.Enable                = 'off';
            app.CISignificanceEditField.Enable              = 'off';
            app.BootstrapSizeinEditField.Enable             = 'off';
        end
        
        function EnableAdvancedOptionsModelValidationPanel(app)
            if(app.IsModelComputed == 1)
                if(app.StaticButton.Value == 0)
                    app.CrossValidateButton.Enable      = 'on';
                    app.ModelComparisonButton.Enable    = 'on';
                else
                    app.CrossValidateButton.Enable      = 'off';
                    app.ModelComparisonButton.Enable    = 'off';
                end
            
                app.NoofBootstrapsEditField.Enable      = 'on';
                app.SizeofBootstrapsEditField.Enable    = 'on';
                app.CISignificanceEditField.Enable      = 'on';
                app.BootstrapSizeinEditField.Enable     = 'on';
                
                app.NoofBootstrapsEditField.Value      = app.NumberOfBootstraps;
                app.SizeofBootstrapsEditField.Value    = app.SizeOfBootstraps;
                app.CISignificanceEditField.Value      = 100-app.AlphaValue;
                app.BootstrapSizeinEditField.Value     = app.BootstrapSizePercent;
                    
            else
                app.NoofBootstrapsEditField.Enable              = 'off';
                app.SizeofBootstrapsEditField.Enable            = 'off';
                app.CrossValidateButton.Enable                  = 'off';
                app.ModelComparisonButton.Enable                = 'off';
                app.CISignificanceEditField.Enable              = 'off';
                app.BootstrapSizeinEditField.Enable             = 'off';
            end
            
           
        end
        
        function RefreshAdvancedOptionsModelValidationPanel(app)
            app.EnableAdvancedOptionsModelValidationPanel();
        end


      %%% Add all UI conditions properties in the above block only 
        
      %%% Functions handling setting/passing of user options properties
        
        %% Functions to Handle the Estimate of Order, Covariance and Model
        
        function InstantiateAStaticModel(app)
            
            
            app.XPCAObj.m_setModel("STATIC_MODEL");
            
            if (~app.NoiselessButton.Value)
                app.SetUpVarianceModel();
                app.XPCAObj.m_setNoiseModel(NoiseModelEnums.NOISY);
            else
                app.XPCAObj.m_setNoiseModel(NoiseModelEnums.NOISELESS);
            end
        
            app.ConfigureOrderCalculator();
        end
        
        function InstantiateADynamicModel(app)
            
            app.XPCAObj.m_setModel("DYNAMIC_MODEL");
            app.XPCAObj.m_setNoOfInputsAndOutputs(app.DynamicObj.NoOfInputs, app.DynamicObj.NoOfOutputs);
            app.XPCAObj.m_setPreprocessor("NULL");
            app.XPCAObj.m_preprocessData();
            app.XPCAObj.m_configureDynamicModel(app.DynamicObj.Lag);
            app.XPCAObj.m_stackDataByLag();
        
            if (~app.NoiselessButton.Value)
                app.SetUpVarianceModel();
                app.XPCAObj.m_setNoiseModel(NoiseModelEnums.NOISY);
            else
                app.XPCAObj.m_setNoiseModel(NoiseModelEnums.NOISELESS);
            end
           % app.XPCAObj.m_setErrorVariances([0.15;0.25]);
            app.ConfigureOrderCalculator();
            
            
        end


        function ConstructModelConfigurationObject(app)
            %app.StaticButton.Value ---->here it is ok
            %StaticButton is a default app component property
            
            if (app.StaticButton.Value == 1 )
                %disp('create a static obj')
                app.ConstructStaticObject();
            else
                %disp('create a dynamic obj')
                app.ConstructDynamicObject();
            end
            app.CreateVarianceObject();
        end
        
        %% Functions to Initialize the data objects
        function ConstructStaticObject(app)
            
            app.DynamicObj = [];
            
            app.StaticObj.ModelType                         = "Static";
            app.VarianceModelObj.VarianceCovarianceMapper   = "DIAGONAL_MAPPER";
        end
        
        function ConstructDynamicObject(app)
            app.UpdateDynamicObject();
        end
        
        function UpdateDynamicObject(app)
            
            app.StaticObj = [];
            
            app.DynamicObj.ModelType                        = "Dynamic";
            %class(app.LagEditField.Value)
            app.DynamicObj.Lag                              = app.LagEditField.Value;
            app.DynamicObj.NoOfInputs                       = size(app.InputColumns,2);%app.NoInputsEditField.Value;
            app.DynamicObj.NoOfOutputs                      = size(app.OutputColumns,2);%app.NoOutputsEditField.Value;
            app.DynamicObj.ModelOrderCalculatorType         = app.ModelOrderEstDropDown.Value; %Check if this is necessary
            app.VarianceModelObj.VarianceCovarianceMapper   = "DIPCA_MAPPER";
        end
        
        function CreateVarianceObject(app)
            if(~app.KnownButton.Value)
                app.VarianceModelObj.VarianceModel              = "UNKNOWN";
%                 app.VarianceModelObj.VarianceCovarianceMapper   = "DIPCA_MAPPER";
%                 app.VarianceModelObj.VarianceSolver             = app.VarianceEstDropDown.Value;
            else
                app.VarianceModelObj.VarianceModel              = "KNOWN";
            end
%             app.VarianceModelObj.VarianceCovarianceMapper   = "DIPCA_MAPPER";
             app.VarianceModelObj.VarianceSolver             = app.VarianceEstDropDown.Value;
        end


        function ConfigureOrderCalculator(app)
            % Check the Order calculator type and configure accordingly
            value = app.ModelOrderEstDropDown.Value;
            %struct(OrderCalculatorEnums.EIGEN_PLOTS).Data
            
            switch str2double(value)
                
                case struct(OrderCalculatorEnums.USER_DEFINED).Data
                    
                    %disp(" Load order value in a another window and configure order calculator");
                    app.OrderInputApp = OrderValueInputApp(app);
                    waitfor(app.OrderInputApp)
                    
                    app.XPCAObj.m_configureOrderCalculatorUserDefinedOrder(app.Ordervalue);
                    
                case struct(OrderCalculatorEnums.HYPOTHESIS_TESTING).Data
                    
                   app.XPCAObj.m_configureOrderCalculatorForHypothesisTesting();
                    
                case struct(OrderCalculatorEnums.EIGEN_PLOTS).Data
                    
                    warndlg('Functionality Under Construction','Warning');
                    
                otherwise
                    
                    %disp('UNDEFINED ORDER CALCULATOR TYPE')
            end
           
        end


    % Initial set up for Variance model has MLE Solver as default. Can be changed later
        function SetUpVarianceModel(app) %here known type temporarily is not passed at all.
            
            app.XPCAObj.m_setVarianceModel(upper(app.VarianceModelObj.VarianceModel));
            if (~app.IsknownError)

                if (app.StaticButton.Value == 1)
                    app.XPCAObj.m_configureVarianceModelForDiagonalMapper();
                else
                    app.XPCAObj.m_configureVarianceModelForDIPCA();
                end
                
                app.XPCAObj.m_setVarianceSolver("MLE_SOLVER");
                
                app.XPCAObj.m_initializeErrorVariances();
                
            else
                %disp(' to set the error variances from UI')
                if (app.IsStatic) %here you have not created the VarianceModel Object itself
                    app.XPCAObj.m_configureVarianceModelForDiagonalMapper();
                else
                    app.XPCAObj.m_configureVarianceModelForDIPCA();%
                    app.XPCAObj.m_initializeErrorVariances();
                    %solver won't be required here right?
                end
            end
            
            
        end
        
        function DeleteModelValidationInfo(app)
            
            app.ComputeConfidenceIntervalsButtonPushedState = 0;
            app.ComputeResidualsButtonPushedState           = 0;
            app.CrossValidateButtonPushedState              = 0;
            app.CrossValidAppButtonPushedState              = 0;
            app.crossData                                   = [];
            app.crossData_Label                             = '';
            
        end
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function InputOutputXPCAObject(app)
            if(~app.IsStatic)
                InputCol = app.InputColumns; 
                OutputCol= app.OutputColumns;
%                 app.data(1:end,InputCol)
%                 app.data(1:end,OutputCol)
                newdata = [app.data(1:end,InputCol) app.data(1:end,OutputCol)]; 
                %newdata
                app.XPCAObj = xpca(newdata);
            else
                InputCol = app.InputColumns; 
                OutputCol= app.OutputColumns;
%                 app.data(1:end,InputCol)
%                 app.data(1:end,OutputCol);
                newdata = [app.data(1:end,OutputCol) app.data(1:end,InputCol) ];
                app.XPCAObj = xpca(newdata);
            end
            
        end
        
        
        function ret_code = ComputeOrder(app)
            
            [app.Order, app.Eigenvalues] = app.XPCAObj.m_computeOrder();
            order       = app.Order;
            input_order = size(app.InputColumns,2);
            
            if(app.IsStatic == 1 &&  order ~= input_order)
                warndlg('Number of Constraints is incorrect','Warning');
                ret_code = app.FAILED;
                return;
            end
            
            if ( app.Order == OrderCalculatorEnums.DEFAULT_ORDER())
                %disp("The order could not be calculated")
                warndlg('Order Could not be Calculated','Warning');
                ret_code = app.FAILED;
            else
                
%                 if (app.StaticButton.Value == 1)
%                     app.XPCAObj.m_restackByOrder();
%                 end
                
                if ( (app.StaticButton.Value == 1) && (app.Order == app.XPCAObj.m_getTotalNumberOfVariables() ) && app.NoiselessButton.Value)
                    warndlg('Could not find dependent variables: Check to see if data is noisy');
                    ret_code = app.FAILED;
                    return;
                end
                
                ret_code = app.SUCCESS;
            end
            
        end
        
        function status = ComputeVariances(app)
            status = 0;
            
           if (app.DynamicButton.Value==1)
               if(app.UnknownButton_2.Value==1)
                   app.XPCAObj.m_computeCovarianceStackedByLag(); 
               else
                   app.XPCAObj.m_computeCovarianceStackedByOrder();
               end
           else
               %disp('To Initialize Static Object and set up variance model here and compute')
               [~, app.Eigenvalues] = app.XPCAObj.m_computeCovariancesForStaticCase();
           end
           
           app.variance_model = app.XPCAObj.m_getVarianceModel();
           app.NoiseVariances = app.variance_model.m_getErrorVariances();
           
           if(~isempty(app.NoiseVariances))
                status = 1;
            end
            return_code        = app.variance_model.m_getVarianceStatus();
            
            if(return_code == VarianceComputationStatusEnums.VARIANCE_COMPUTATION_FAILED_DUE_TO_UNKNOWN_REASON)
                warndlg('The Variance Algorithm did not converge. Please try changing the Input order.')
                status = 0;
            end
            
        
        end
        
        function BuildModel(app)
            app.Model = app.XPCAObj.m_computeModel();       
        end
    
        function ComputeConfidenceIntervals(app)
            %disp('compute confidence intervals here')
            
              [app.ConfidenceIntervals] = app.XPCAObj.m_computeConfidenceIntervals(app.NumberOfBootstraps, ...
                                                                    app.SizeOfBootstraps, ...
                                                                    app.AlphaValue,app.IsStatic) ;

            
        end
        
        function output_app = GetOutputApp(app)
            
           if (isempty(app.OutApp) || ~isvalid(app.OutApp)) 
                %disp('out app is empty. Instantiating')
                app.OutApp = OutputApp(app);
           end
           output_app = app.OutApp;
        end
        
        function cross_app = GetCrossValidationApp(app)
            if (isempty(app.CrossApp) || ~isvalid(app.CrossApp)) 
                %app.crossData
                
                app.CrossApp = Cross_validation(app, app.XPCAObj, app.crossData, app.crossData_Label, app.CrossValidAppButtonPushedState);
           end
           cross_app = app.CrossApp;
        end
        
        function LoadKnownVariance(app)
            %app.VarianceModelObj.VarianceModel
            if (app.KnownButton.Value == 1)
%                 if(app.StaticButton.Value == 1)
%                     msgbox('Please Enter Variances in the Table','Enter Variance');
%                     sizedat = size(app.data,2);
%                     t = 1:sizedat;
%                     app.UITable.RowName = "Variable " + t;
%                     app.UITable.ColumnName = {'Variance'};
%                     app.UITable.ColumnEditable = true(1,sizedat);
%                     app.UITable.Data = zeros(sizedat,1);
%                     %app.IsNoiseVarianceComputed = true;
%                 else
                    
                    maxval = size(app.data,2);
                    
                    app.InputColumns  = str2num(app.InputcolEditField.Value);
                    app.OutputColumns = str2num(app.OutputcolEditField.Value);
                    
                    if( ~isempty(app.InputColumns) && ~isempty(app.OutputColumns))
                        if((max(app.InputColumns)<=maxval && max(app.OutputColumns)<=maxval))
                            
                            if(intersect(app.InputColumns,app.OutputColumns))
                                app.UnknownButton.Value = 1;
                                app.HideAdvancedOptions();
                                
                                warndlg('Input and Output should not have the same data')
                                
                                return;
                            else
%                                 if(app.IsLoadedState == 0)
%                                     msgbox('Please Enter Variances in the Table','Enter Variance');
%                                 end
                                sizedatInput = size(app.InputColumns,2);
                                sizedatOutput= size(app.OutputColumns,2);
                                sizedat = sizedatInput+sizedatOutput;
                                t1 = 1:sizedatInput;
                                t2 = 1:sizedatOutput;
                                rownames = [];
                                
                               
                                a = "Input " + t1;
                                b = "Output "+ t2;
                                rownames = [a b];
                                
                                app.UITable.RowName = rownames;
                                %app.UITable.RowName(4) = "Output " + t2(1);
                                
                                app.UITable.ColumnName = {'Variance'};
                                    
                                
                                app.UITable.ColumnEditable = true(1,sizedat);
                                app.UITable.Data = zeros(sizedat,1);
%                                 app.InputOutputXPCAObject();
%                                 app.UpdateDynamicObject();
                            end
                            
                        else
                            app.UnknownButton.Value = 1;
                            app.HideAdvancedOptions();
                            warndlg('Value Entered is greater than the column dimension of the Loaded data')
                            return;
                        end
                        
                    else
                        app.UnknownButton.Value = 1;
                        app.HideAdvancedOptions();
                        warndlg('Enter data columns only in the format [2:4,7] for columns 2,3,4,7, also note the maximum value should not exceed columns in Loaded Data')
                        return;
                    end
            end
            %end
            
        end
        
        function status = VarianceSanity(app)
            status = 1;
            %app.NoiseVariances
            if(isempty(app.UITable.Data))
                msgbox('Please press Done Button after entering Noise Variances in the UITable')
                status = 2;
                return;
            end
            AllZero = sum(app.UITable.Data(:) == 0);
            result = sum(isnan(app.UITable.Data));
            totalVar = size(app.InputColumns,2)+size(app.OutputColumns,2);
            if(app.NoiseVariances<0  )
                status = 0;
                return;
            elseif(AllZero==totalVar)
                msgbox('If Data is Noiseless, please select Noiseless Option in Noise Model')
                status = 0;
                return;
            end
            
            if(result>0)
                status = 0;
                return;
            end
        end
        
        function VarianceOrder(app)
            if(app.StaticButton.Value == 1)                             % first output and then input
                
                no_of_inputs  = size(app.InputColumns,2);
                no_of_outputs = size(app.OutputColumns,2);
                
                app.NoiseVariances = [app.UITable.Data(no_of_inputs+1:no_of_inputs+no_of_outputs);app.UITable.Data(1:no_of_inputs)];
            else
                app.NoiseVariances = app.UITable.Data;
            end
        end
        
        function VarianceStatic = VarianceOrderStatic(app,noiseVariance)
            if(app.StaticButton.Value == 1)                             % first output and then input
                
                no_of_inputs  = size(app.InputColumns,2);
                no_of_outputs = size(app.OutputColumns,2);
                
                VarianceStatic = [noiseVariance(no_of_outputs+1:no_of_outputs+no_of_inputs);noiseVariance(1:no_of_outputs)];
            end
        end
        
        
        function ComputeResiduals(app)
            disp('compute Residuals here')
            [app.Residuals, ~, ~] = app.XPCAObj.m_computeResiduals();
            app.resid_fig = figure ;
            autocorr(app.Residuals);
            hold on
            ylabel('Sample')
            legend('Sample corr','95% significance level')
            hold off
        end
        
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.ReloadUIAndInitializeDataObjects();
            app.ConstructModelConfigurationObject();
            app.DisableModelConfigurationPanel();
            app.DisableModelEstimationPanel();
            app.DisableModelValidationPanel();
            app.HideAdvancedOptions();
            
            app.DisableAdvancedOptionsModelConfigurationPanel();
            app.DisableAdvancedOptionsModelValidationPanel();
            %rectangle('position',[25,25,50,50]);
            w = warning('query','last');
            id = w.identifier;
            warning('off',id);
        end

        % Value changed function: LoadDataDropDown
        function LoadDataDropdown(app, event)
            
            value = app.LoadDataDropDown.Value;
            
            if (value ==  "Data File")
                [file,path] = uigetfile('*.mat');
                filename = fullfile(path, file);
                
                S = load(filename);
                names = fieldnames(S);
                
                
                if (length(names) == 1)
                    
                    app.data = S.(names{1});
                    %a = app.data
                    dimensions = size(app.data);
                    
                    %For disabling Loading of Data
                    app.LoadDataDropDown.Enable = false;
                    
                    app.FileLabel.Text = "File Loaded: " + file;
                    app.FileLabel_2.Text = dimensions(1,1)+"x"+dimensions(1,2);
                    app.FileLabel.Tooltip = app.FileLabel.Text;
                    app.FileLabel_2.Tooltip = app.FileLabel_2.Text;
                    app.IsFileLoaded = true;
                    %app.XPCAObj = xpca(app.data);
                    
                    if(dimensions(1,2)==2)
                        app.InputColumns = 1;
                        app.OutputColumns= 2;
                        app.InputcolEditField.Value = num2str(app.InputColumns);
                        app.OutputcolEditField.Value= num2str(app.OutputColumns);
                    end
                    
                    if(dimensions(1,1)>500)
                        app.SizeOfBootstraps = 400;
                        app.BootstrapSizePercent = round((400/dimensions(1,1))*100);
                    else
                        app.SizeOfBootstraps = round((dimensions(1,1)*app.BootstrapSizePercent)/100);
                    end
                    
                else
                    if (numel(S) == 0)
                        warndlg('No data variable in the file','Warning');
                        app.LoadDataDropDown.Value = "Select Input Type";
                        return;
                    else
                        warndlg('Check to see if the input file contains only one variable','Warning');
                        app.LoadDataDropDown.Value = "Select Input Type";
                        return;
                    end
                end
                app.RefreshModelConfigurationPanel();
                app.ReloadUIAndInitializeDataObjects();
                app.RefreshModelEstimationPanel();
            elseif (value ==  "Load from Environment")
                WorkSpaceApp(app);
            elseif (value== "IDData")
                
            end
            
        end

        % Selection changed function: ModelTypeButtonGroup
        function ModelTypeChanged(app, event)
            
            if(app.KnownButton.Value == 1 && app.IsLoadedState == 0)
                app.UITable.Data   = [];
                app.NoiseVariances = [];
                
                app.UnknownButton.Value = 1;
                app.KnownButton.Value   = 0;
                msgbox('Enter Variances again')
                
                app.ReloadUIAndInitializeDataObjects();
                app.ConstructModelConfigurationObject();
                app.RefreshModelConfigurationPanel();
                app.RefreshAdvancedOptionsModelConfigurationPanel();
                app.RefreshModelEstimationPanel();

                %app.LoadKnownVariance();
                return;
            end
            
            if(app.StaticButton.Value == 1)
                app.IsStatic = true;
            else
                app.IsStatic = false;
            end
            
            app.ReloadUIAndInitializeDataObjects();
            app.ConstructModelConfigurationObject();
            app.RefreshModelConfigurationPanel();
            app.RefreshAdvancedOptionsModelConfigurationPanel();
            app.RefreshModelEstimationPanel();
        end

        % Value changed function: LagEditField
        function LagChangedHandler(app, event)
            app.IsModelOrderComputed = false;
            app.IsModelComputed      = false;
            app.RefreshModelEstimationPanel();
            app.UpdateDynamicObject();
        end

        % Selection changed function: NoiseVariancesButtonGroup_2
        function NoiseVarianceOptionChanged(app, event)
         
            if (app.UnknownButton.Value == 1)
                app.IsknownError = false;
                app.VarianceModelObj.VarianceModel = "UNKNOWN";
                app.IsNoiseVarianceComputed = 0;
                app.HideAdvancedOptions();
            else
                app.IsknownError = true;
                app.VarianceModelObj.VarianceModel = "kNOWN";
                if(app.StaticButton.Value == 1)
                    app.DynamicButton.Enable = 'off';
                else
                    app.StaticButton.Enable  = 'off';
                end
                app.ShowAdvancedOptions();
                app.LoadKnownVariance();
                msgbox('Please Enter Variances in the Table','Enter Variance');
            end
            
            app.RefreshModelConfigurationPanel();
            app.RefreshAdvancedOptionsModelConfigurationPanel();
            app.RefreshModelEstimationPanel();
        end

        % Callback function
        function DPAOButtonClickedHandler(app, event)
            app.AdvancedOptionsDataProcessingPanel.Visible = 'on';
        end

        % Callback function
        function MCAOButtonClickedHandler(app, event)
            app.AdvancedOptionsModelConfigurationPanel.Visible = 'on';
        end

        % Callback function
        function MEAOButtonClickedHandler(app, event)
            app.AdvancedOptionsModelEstimationPanel.Visible ='on';
        end

        % Button pushed function: AdvOptionsButton
        function AdvancedOptionsButtonClickedHandler(app, event)
            app.AdvancedOptions = ~(app.AdvancedOptions);
            if ( app.AdvancedOptions)
                app.ShowAdvancedOptions();
            else
                app.HideAdvancedOptions();
            end
        end

        % Value changed function: ModelOrderEstDropDown
        function ModelOrderEstChanged(app, event)
            
            value = app.ModelOrderEstDropDown.Value;
            %OrderCalculatorEnums.DEFAULT_ORDER
            switch str2double(value)
                
                case struct(OrderCalculatorEnums.USER_DEFINED).Data
                    
                    disp("Order Calculator : User Defined");
                    
                case struct(OrderCalculatorEnums.HYPOTHESIS_TESTING).Data
                    
                    %app.DynamicObj.ModelOrderCalculatorType = "HYPOTHESIS_TESTING";
                    app.UpdateDynamicObject();
                    
                case struct(OrderCalculatorEnums.EIGEN_PLOTS).Data
                    
                    warndlg('Functionality Under Construction','Warning');
                    app.ModelOrderEstDropDown.Value = num2str(struct(OrderCalculatorEnums.HYPOTHESIS_TESTING).Data);
                    
                otherwise
                    
                    disp('UNDEFINED ORDER CALCULATOR TYPE')
                    
            end
            
        end

        % Value changed function: VarianceEstDropDown
        function VarianceEstDropDownValueChanged(app, event)
            
            
            value = app.VarianceEstDropDown.Value;
            
            if (value ~= "Maximum Likelihood Estimator")
                warndlg('Functionality Under Construction','Warning');
                app.VarianceEstDropDown.Value       = "Maximum Likelihood Estimator";    
            else
                disp("setting variance model successful")    
            end
            
            app.VarianceModelObj.VarianceSolver     = app.VarianceEstDropDown.Value;
            
        end

        % Button pushed function: BuildModelButton
        function BuildModelButtonClicked(app, event)
            
            
            if(app.IsModelComputed == 1 && app.IsLoadedState == 0)
                
%                 if(app.ParameterChanged == 0)
%                     msgbox('No parameters were changed. Please change parameters to create new model');
%                     return;
%                 end
                
                input = questdlg('Confidence intervals, Compute Residuals and Cross Validation,etc. will use the new model','Confirmation','OK','Cancel','Cancel');
                switch input
                    case 'OK'
                        app.DeleteModelValidationInfo();
%                         app.DeleteOrderAndVarianceInfo();
%                         app.DisableModelValidationPanel();
%                         app.DisableAdvancedOptionsModelValidationPanel();
                        DeleteConfIntAndGraph(app.OutApp,app.KnownButton_2.Value)
                        delete(app.CrossApp)
                        delete(app.resid_fig)
                    case'Cancel'
                        return;
                        
                end
            end
            
            n =size(app.data);
            maxval = n(2);
            
            app.InputColumns  = str2num(app.InputcolEditField.Value);
            app.OutputColumns = str2num(app.OutputcolEditField.Value);
            
            if( ~isempty(app.InputColumns) && ~isempty(app.OutputColumns))
                if((max(app.InputColumns)<=maxval && max(app.OutputColumns)<=maxval))
                    
                    if(intersect(app.InputColumns,app.OutputColumns))
                        warndlg('Input and Output should not have the same data')
                        return;
                    else
                        app.InputOutputXPCAObject();
                        if(~app.IsStatic)
                            app.UpdateDynamicObject();
                        end
                    end
                    
                else
                    warndlg('Value Entered is greater than the column dimension of the Loaded data')
                    return;
                end
                
            else
                warndlg('Enter columns only in the format [2:4,7] for columns 2,3,4,7, also note the maximum value should not exceed columns in Loaded Data')
                return;
            end
            
            if(app.IsFileLoaded == 1)
                app.InputOutputXPCAObject();
            else
                return;
            end
            
            
            if(app.IsModelOrderComputed == 0)
                
                if(app.KnownButton_2.Value == 1 )
                    warndlg('Please press DONE button after you enter order in the Enter order field.');
                    return;
                end
                
                if(isempty(app.NoiseVariances) && app.KnownButton.Value == 1)
                    warndlg('Data is Empty, please enter variances in Advanced Variances Table and press DONE button.')
                    return;
                end
                
                a = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Message','Computing the Order');
                
                %app.DisableModelConfigurationPanel();
                app.LagEditField.Enable                 = 'on';
                
                %disp(' send the data to the backend and instantiate a dynamic object and compute order')
                
                if (app.StaticButton.Value == 0)
                    app.InstantiateADynamicModel();    
                else
                    app.InstantiateAStaticModel();
                end


                if(app.IsNoiseVarianceComputed == 1)                                %for known variance
                    
                    app.XPCAObj.m_setErrorVariances(app.NoiseVariances);    %do not change the order of this statement
                    if(app.IsStatic == 0)
                        DisplayErrorVariances(app.GetOutputApp(), app.NoiseVariances,size(app.InputColumns,2),size(app.OutputColumns,2));
                    else
                        VarianceStatic = app.VarianceOrderStatic(app.NoiseVariances);
                        DisplayErrorVariances(app.GetOutputApp(),VarianceStatic,size(app.InputColumns,2),size(app.OutputColumns,2));
                    end
                    
                end
                
                a.Value = .33; 
                a.Message = 'Computing Order';
                pause(1)


                if (app.ComputeOrder())
                
                    a.Value = .67;
                    a.Message = 'Processing the data';
                    pause(1)
    
                    %app.RefreshModelEstimationPanel();
                
                    a.Value = 1;
                    a.Message = 'Rendering Output';
                    pause(1)
    
                    if (~app.IsStatic)
                        SetModelType(app.GetOutputApp(), ModelTypeEnums.DYNAMIC);
                    else
                        SetModelType(app.GetOutputApp(), ModelTypeEnums.STATIC);
                    end
                    
                    SetOrder(app.GetOutputApp(), app.Order);
                    DisplayEigenValues(app.GetOutputApp(), app.Eigenvalues, app.IsknownOrder);
                    app.BuildModelButtonClickedState = 1;
                    app.IsModelOrderComputed                = 1;
                else
                    app.IsModelOrderComputed                = 0;
                    close(a)
                    return;
                    
                end
            
                 close(a)
                
            else
                
                if (app.StaticButton.Value == 0)
                    app.InstantiateADynamicModel();    
                else
                    app.InstantiateAStaticModel();
                end
                
                if(app.IsModelOrderComputed == 1)
                   app.XPCAObj.m_configureOrderCalculatorUserDefinedOrder(app.Order);
                end


                if(app.IsNoiseVarianceComputed == 1)                                %for known variance
                    app.XPCAObj.m_setErrorVariances(app.NoiseVariances);    %do not change the order of this statement
                    if(app.IsStatic == 0)
                        DisplayErrorVariances(app.GetOutputApp(), app.NoiseVariances,size(app.InputColumns,2),size(app.OutputColumns,2));
                    else
                        VarianceStatic = app.VarianceOrderStatic(app.NoiseVariances);
                        DisplayErrorVariances(app.GetOutputApp(),VarianceStatic,size(app.InputColumns,2),size(app.OutputColumns,2));
                    end
                end
                
                if (~app.IsStatic)
                    SetModelType(app.GetOutputApp(), ModelTypeEnums.DYNAMIC);
                else
                    SetModelType(app.GetOutputApp(), ModelTypeEnums.STATIC);
                end
                    
                    SetOrder(app.GetOutputApp(), app.Order);
                    DisplayEigenValues(app.GetOutputApp(), app.Eigenvalues, app.IsknownOrder);
                    app.BuildModelButtonClickedState = 1;
            end
               
                app.RefreshModelEstimationPanel();
                
   %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           
           if(app.UnknownButton.Value ==1)
                 b = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Message','Computing Error Variance');
                
                %app.DisableModelConfigurationPanel();
                
                %disp('Check Static or Dynamic Case; static createobj and compute; dynamic just compute');
                
    %            if (app.IsStatic)
    %                app.InstantiateAStaticModel();
    %            end
                    
                b.Value = 0.3;
                b.Message = 'Computing Error Variance';
                pause(1)
                
                app.ComputeVariances();
                
                app.IsNoiseVarianceComputed = true;    
    %            app.RefreshModelEstimationPanel();
                if (app.IsStatic)
                    SetModelType(app.GetOutputApp(), ModelTypeEnums.STATIC);
                end
                
                b.Value = 1;
                b.Message = 'Rendering the Output';
                pause(1)
                
                DisplayEigenValues(app.GetOutputApp(), app.Eigenvalues, app.IsknownOrder);
                if(app.IsStatic == 0)
                        DisplayErrorVariances(app.GetOutputApp(), app.NoiseVariances,size(app.InputColumns,2),size(app.OutputColumns,2));
                    else
                        VarianceStatic = app.VarianceOrderStatic(app.NoiseVariances);
                        DisplayErrorVariances(app.GetOutputApp(),VarianceStatic,size(app.InputColumns,2),size(app.OutputColumns,2));
                end
                
                close(b);
                app.BuildModelButtonClickedState = 1;
           else
               
               if (app.IsStatic)
                    SetModelType(app.GetOutputApp(), ModelTypeEnums.STATIC);
                end
                % Redundant, but useful when the user closes the Ouput window and computes error variances
                
                DisplayEigenValues(app.GetOutputApp(), app.Eigenvalues, app.IsknownOrder);
                if(app.IsStatic == 0)
                        DisplayErrorVariances(app.GetOutputApp(), app.NoiseVariances,size(app.InputColumns,2),size(app.OutputColumns,2));
                    else
                        VarianceStatic = app.VarianceOrderStatic(app.NoiseVariances);
                        DisplayErrorVariances(app.GetOutputApp(),VarianceStatic,size(app.InputColumns,2),size(app.OutputColumns,2));
                end
                app.BuildModelButtonClickedState = 1;
           end
                app.RefreshModelEstimationPanel();
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
            'Message','Computing the Model');
        
            %disp('build model using the variances')
            
            d.Value = 0.3;
            d.Message = 'Computing Model';
            pause(1)
            
            app.BuildModel();
            app.IsModelComputed = true;
            
%             if (~app.IsStatic)
                app.RefreshModelValidationPanel();
                app.RefreshAdvancedOptionsModelValidationPanel();
%             end
            
            d.Value = 1;
            d.Message = 'Rendering Output';
            pause(1)
            
            if(app.IsStatic == 1)
                
            end
            
            if(app.IsStatic ==1)
                
                Regression_model = rref(app.Model');           %for regression model
                sz = size(app.InputColumns,2);
                Regression_model = -Regression_model(1:end,end-sz+1:end);
                DisplayModel(app.GetOutputApp(), Regression_model);
            else
                DisplayModel(app.GetOutputApp(), app.Model);
            end
            
            close(d);
            app.BuildModelButtonClickedState = 1;
            if(app.IsLoadedState == 0)
                app.DisableModelConfigurationPanel();
                app.DisableAdvancedOptionsModelConfigurationPanel();
                app.DisableModelEstimationPanel();
                app.RefreshAdvancedOptionsModelValidationPanel();

            end
        end

        % Callback function
        function NoInputsEditFieldValueChanged(app, event)
                %value = app.NoInputsEditField.Value;
                app.UpdateDynamicObject();
        end

        % Callback function
        function NoOutputsEditFieldValueChanged(app, event)
                %value = app.NoOutputsEditField.Value;
                app.UpdateDynamicObject();
        end

        % Button pushed function: ComputeConfidenceIntervalsButton
        function ComputeConfidenceIntervalsButtonPushed(app, event)
            
            
            app.ComputeConfidenceIntervalsButtonPushedState = 1;
            app.ShowAdvancedOptions();
            n = size(app.data);
            n = n(1);
            
            if(app.SizeOfBootstraps > n)
                warning('Size of Bootstrap should be less than size of data');
                return;
            end
            if(app.IsLoadedState == 0)
                h = questdlg('Check Confidence Interval Parameters',...
                    'Confindence Interval Parameters',...
                    'OK','Change Parameters','Change Parameters');
                
                switch h
                    case 'OK'
                        
                    case 'Change Parameters'
                        return;
                end
            end
            
            SetP_Value(app.GetOutputApp(), 1-(app.CISignificanceEditField.Value/100));
            app.SizeOfBootstraps     = app.SizeofBootstrapsEditField.Value;
            app.NumberOfBootstraps   = app.NoofBootstrapsEditField.Value;
            app.AlphaValue           = 100-app.CISignificanceEditField.Value;
            
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
            'Message','Computing the Confidence Intervals');
        
            %disp('build model using the variances')
            
            d.Value = 0.3;
            d.Message = 'Computing Confidence Intervals';
            pause(1)
            
            app.ComputeConfidenceIntervals();
            
            d.Value = 1;
            d.Message = 'Rendering Output';
            pause(1)
            
           
            DisplayConfidenceIntervals(app.GetOutputApp(), app.ConfidenceIntervals);
            close(d);
            
            if(app.IsLoadedState == 1)
                if(app.ComputeResidualsButtonPushedState == 0 && app.CrossValidAppButtonPushedState == 0)
                    
                    app.IsLoadedState = 0;
                end
            else
                app.DisableModelConfigurationPanel();
                app.DisableAdvancedOptionsModelConfigurationPanel();
                app.DisableModelEstimationPanel();
            end
            %app.ComputeConfidenceIntervalsButton.Enable = 'off';    
        end

        % Button pushed function: SaveModelButton
        function SaveModelButtonPushed(app, event)
            
            if (app.IsStatic)
                
                noise_var = app.VarianceOrderStatic(app.NoiseVariances);
                
                Regression_model = rref(app.Model');           %for regression model
                sz = size(app.InputColumns,2);
                Regression_model = -Regression_model(1:end,end-sz+1:end);
                
                model_type = "Static model";
                
                
                if(app.KnownButton.Value == 1)
                    variance_type = "Known Variance";
                else
                    variance_type = "Unknown Variance";
                end
                
                if(app.KnownButton_2.Value == 1)
                    order_type = "Known Order";
                else
                    order_type = "Unknown Order";
                end
                
                report = {model_type,variance_type,order_type};
                
                ModelObject = CrossValidUtils.m_createXPCAStaticObject(Regression_model,noise_var,report,'XPCAStatic','CurrentSession Model');
                SaveModelStatic(ModelObject)
                
            else
                model = app.Model;
                numerator = model(1:size(model)/2)';
                denominator = model(size(model)/2+1:size(model))';
                noise_var = app.NoiseVariances;
                
                model_type = "Dynamic model";
                
                
                if(app.KnownButton.Value == 1)
                    variance_type = "Known Variance";
                else
                    variance_type = "Unknown Variance";
                end
                
                if(app.KnownButton_2.Value == 1)
                    order_type = "Known Order";
                else
                    order_type = "Unknown Order";
                end
                report = {model_type,variance_type,order_type};
                ModelObject = CrossValidUtils.m_createXPCAObject(numerator,denominator,[],[],noise_var,report,[],[],'XPCA','CurrentSession Model');
                SaveModel(ModelObject)
%                 data_mat   = app.data;
%                 model_type = "Dynamic";
%                 
%                 order       = app.Order
%                 noise_var   = app.NoiseVariances;
%                 model       = app.Model
%                 confidence_intervals = app.ConfidenceIntervals;
%            
%                 vars = {'data_mat', 'model_type', 'order', 'noise_var', 'model', 'confidence_intervals'};
            end
            
            
%             date_str = datestr(datetime('now'));
%             uisave( vars, strcat('xpca-', date_str(isstrprop(date_str,'alphanum'))))
        end

        % Selection changed function: NoiseModelButtonGroup
        function NoiseButtonChangedHandler(app, event)
            
            
            if(app.NoiselessButton.Value == 0)
                app.IsNoiseVarianceComputed = 0;
            else
                app.IsNoiseVarianceComputed = 1;
            end
            
            
            app.RefreshModelConfigurationPanel();
            app.RefreshAdvancedOptionsModelConfigurationPanel();
            app.RefreshModelEstimationPanel();
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            
                  if(app.KnownButton.Value == 1)
                        if(~any(isnan(app.UITable.Data))&&(~any(app.UITable.Data<0)))
                     
                            status = app.VarianceSanity();
                            
                            if(status == 1)
                                app.VarianceOrder();
                                %app.NoiseVariances = app.UITable.Data;
                                app.RefreshModelConfigurationPanel();
                                app.RefreshAdvancedOptionsModelConfigurationPanel();
                                app.RefreshModelEstimationPanel();
                                app.HideAdvancedOptions();
                                
                                app.IsNoiseVarianceComputed = 1;                %has to be before app.RefreshModelEstimationPanel()
                                app.DoneButtonPushedState = 1;
                            else
                                return;
                            end
                            
                        else 
                            
                            sizedat = size(app.data,2);
                            app.UITable.Data = zeros(sizedat,1);
                            warndlg('Please enter non-negative numerical values only!','Warning');
                            return;
                            
                        end
                  end
                  
                  if(app.KnownButton_2.Value == 1)
                        order = app.EnterOrderEditField.Value;
                        if(order >=0 && ~isnan(order))
                            app.Order = order;
                            
                            app.RefreshModelConfigurationPanel();
                            app.RefreshAdvancedOptionsModelConfigurationPanel();
                            app.RefreshModelEstimationPanel();
                            app.HideAdvancedOptions();
                            
                            app.IsModelOrderComputed  = 1;
                            app.DoneButtonPushedState = 1;
                        else
                            warndlg('Model Order must be non-negative and numeric');
                            app.IsModelOrderComputed = 0;
                            return;
                        end
                  end
                 % User should not choose unknown after giving known errors
                %app.KnownButton.Enable = 'off';
                %DisplayErrorVariances(app.GetOutputApp(), app.NoiseVariances);
            %app.VarianceObj.Errors = app.UITable.Data;
            %app.testing();
%               if(~any(~(app.UITable.Data>0)))
%                         disp('yes 911')
%                     end
        end

        % Size changed function: 
        % AdvancedOptionsModelConfigurationPanel
        function AdvancedOptionsModelConfigurationPanelSizeChanged(app, event)
            position = app.AdvancedOptionsModelConfigurationPanel.Position;
            
        end

        % Button pushed function: ComputeResidualsButton
        function ComputeResidualsButtonPushed(app, event)
            if(app.IsLoadedState == 1)
                if(app.CrossValidAppButtonPushedState == 0)
                    
                    app.IsLoadedState = 0;
                
                end
            else
                app.DisableModelConfigurationPanel();
                app.DisableAdvancedOptionsModelConfigurationPanel();
                app.DisableModelEstimationPanel();
            end
            
            app.ComputeResidualsButtonPushedState = 1;
            
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
            'Message','Computing the Residuals');
                    
            d.Value = 0.3;
            d.Message = 'Computing Residuals';
            pause(1)
            
            app.ComputeResiduals();  %this stores 
            
            %app.ComputeResidualsButton.Enable = "off";
        end

        % Button pushed function: CrossValidateButton
        function CrossValidateButtonPushed(app, event)
            if(app.IsLoadedState == 1)
                    
                app.IsLoadedState = 0;
            else
                app.DisableModelConfigurationPanel();
                app.DisableAdvancedOptionsModelConfigurationPanel();
                app.DisableModelEstimationPanel();
            end
            app.CrossValidateButtonPushedState = 1;
            app.GetCrossValidationApp();
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Button pushed function: LoadsessionButton
        function LoadsessionButtonPushed(app, event)
            app.IsLoadedState = 1;
            loadState(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            answer = questdlg('Would you like to save the session?',...
                'User Options', ...
                'Yes','No','No');
            
            %app.WorkSpaceApp = WorkSpaceApp(app);
            delete(app.OutApp)
            delete(app.WorkSpaceApp)
            %delete(app.CrossApp)
            delete(app.resid_fig)
            
            
            %Handle response
            switch answer
                case 'Yes'
                    saveState(app);
                    delete(app.CrossApp)
                    delete(app);
                    
                case 'No'
                    delete(app);
            end
            
        end

        % Button pushed function: ClearsessionButton
        function ClearsessionButtonPushed(app, event)
            input = questdlg('Are you sure you want to clear the current session as all the information in the current session will be lost?','Confirmation','OK','Cancel','Cancel');
                switch input
                    case 'OK'
                        
                    case'Cancel'
                        return;
                        
                end
            delete(app.OutApp)
            delete(app.WorkSpaceApp)
            delete(app.CrossApp)
            delete(app.resid_fig)
            delete(app.CrossApp)
            %delete(CrossValidationApp)
            app.loadClearState();
            
        end

        % Selection changed function: ModelOrderButtonGroup
        function ModelOrderButtonGroupSelectionChanged(app, event)

            if (app.UnknownButton_2.Value == 1)
                app.IsknownOrder = false;
                app.IsModelOrderComputed = 0;
                app.HideAdvancedOptions();
            else
                app.IsknownOrder = true;
                if(app.StaticButton.Value == 1)
                    app.DynamicButton.Enable = 'off';
                else
                    app.StaticButton.Enable  = 'off';
                end
                app.ShowAdvancedOptions();
                
                f = msgbox('Please Enter Model Order','Enter Model Order');
            end
            
            app.RefreshModelConfigurationPanel();
            app.RefreshAdvancedOptionsModelConfigurationPanel();
            app.RefreshModelEstimationPanel();
        end

        % Value changed function: EnterOrderEditField
        function EnterOrderEditFieldValueChanged(app, event)
            if (app.UnknownButton_2.Value == 1)
                app.IsknownOrder = false;
                
                app.HideAdvancedOptions();
            else
                app.IsknownOrder = true;
                if(app.StaticButton.Value == 1)
                    app.DynamicButton.Enable = 'off';
                else
                    app.StaticButton.Enable  = 'off';
                end
                app.ShowAdvancedOptions();
                
                %f = msgbox('Please Enter Model Order','Enter Model Order');
            end
            
            app.RefreshModelEstimationPanel();
            app.RefreshAdvancedOptionsModelConfigurationPanel();
            
        end

        % Callback function
        function Model_4ValueChanged(app, event)
            value = app.Model_4.Value;
            
        end

        % Button pushed function: ModelComparisonButton
        function ModelComparisonButtonPushed(app, event)
            app.ModelComparisonButtonPushedState = 1;
            model = app.Model;
            numerator = model(1:size(model)/2)';
            denominator = model(size(model)/2+1:size(model))';
            noise_var = app.NoiseVariances;
            
            model_type = "Dynamic model";
                
                
            if(app.KnownButton.Value == 1)
                variance_type = "Known Variance";
            else
                variance_type = "Unknown Variance";
            end
            
            if(app.KnownButton_2.Value == 1)
                order_type = "Known Order";
            else
                order_type = "Unknown Order";
            end
            report = {model_type,variance_type,order_type};
                
            ModelObject = CrossValidUtils.m_createXPCAObject(numerator,denominator,[],[],noise_var,report,[],[],'XPCA','CurrentSession Model');
            CrossValidationApp(app.data,ModelObject)
        end

        % Value changed function: SizeofBootstrapsEditField
        function SizeofBootstrapsEditFieldValueChanged(app, event)
            n = size(app.data);
            n = n(1);
            app.BootstrapSizeinEditField.Value = round((app.SizeofBootstrapsEditField.Value/n)*100,1);
        end

        % Value changed function: NoofBootstrapsEditField
        function NoofBootstrapsEditFieldValueChanged(app, event)
            
        end

        % Value changed function: BootstrapSizeinEditField
        function BootstrapSizeinEditFieldValueChanged(app, event)
            n = size(app.data);
            n = n(1);
            app.SizeofBootstrapsEditField.Value = round((app.BootstrapSizeinEditField.Value*n )/100);
            
        end

        % Value changing function: InputcolEditField
        function InputcolEditFieldValueChanging(app, event)
            if(app.KnownButton.Value == 1)
                app.UITable.Data = [];
                app.UnknownButton.Value = 1;
                msgbox('Enter Variances again')
                return;
            end
            
        end

        % Value changing function: OutputcolEditField
        function OutputcolEditFieldValueChanging(app, event)
            if(app.KnownButton.Value == 1)
                app.UITable.Data = [];
                app.UnknownButton.Value = 1;
                msgbox('Enter Variances again')
                return;
            end
            
        end

        % Callback function
        function DoneButton_2Pushed(app, event)
            app.DataProcessingPanel();
            app.InputcolEditField.Enable           = 'off';
            app.InputcolEditFieldLabel.Enable      = 'off';
            app.OutputcolEditField.Enable          = 'off';
            app.OutputcolEditFieldLabel.Enable     = 'off';
            
        end

        % Button pushed function: PreProcessDataButton
        function PreProcessDataButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.XPCAPanel = uipanel(app.UIFigure);
            app.XPCAPanel.Title = 'XPCA';
            app.ModelConfigurationPanel = uipanel(app.UIFigure);
            app.ModelConfigurationPanel.Title = 'Model Configuration';
            app.ModelTypeButtonGroup = uibuttongroup(app.ModelConfigurationPanel);
            app.ModelTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModelTypeChanged, true);
            app.ModelTypeButtonGroup.Title = 'Model Type';
            app.StaticButton = uiradiobutton(app.ModelTypeButtonGroup);
            app.StaticButton.Text = 'Static';
            app.StaticButton.Value = true;
            app.DynamicButton = uiradiobutton(app.ModelTypeButtonGroup);
            app.DynamicButton.Text = 'Dynamic';
            app.NoiseVariancesButtonGroup_2 = uibuttongroup(app.ModelConfigurationPanel);
            app.NoiseVariancesButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @NoiseVarianceOptionChanged, true);
            app.NoiseVariancesButtonGroup_2.Title = 'Noise Variances';
            app.KnownButton = uiradiobutton(app.NoiseVariancesButtonGroup_2);
            app.KnownButton.Text = 'Known';
            app.UnknownButton = uiradiobutton(app.NoiseVariancesButtonGroup_2);
            app.UnknownButton.Text = 'Unknown';
            app.UnknownButton.Value = true;
            app.NoiseModelButtonGroup = uibuttongroup(app.ModelConfigurationPanel);
            app.NoiseModelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NoiseButtonChangedHandler, true);
            app.NoiseModelButtonGroup.Title = 'Noise Model';
            app.NoiselessButton = uiradiobutton(app.NoiseModelButtonGroup);
            app.NoiselessButton.Text = 'Noiseless';
            app.NoisyButton = uiradiobutton(app.NoiseModelButtonGroup);
            app.NoisyButton.Text = 'Noisy';
            app.NoisyButton.Value = true;
            app.ModelOrderButtonGroup = uibuttongroup(app.ModelConfigurationPanel);
            app.ModelOrderButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModelOrderButtonGroupSelectionChanged, true);
            app.ModelOrderButtonGroup.Title = 'Model Order';
            app.KnownButton_2 = uiradiobutton(app.ModelOrderButtonGroup);
            app.KnownButton_2.Text = 'Known';
            app.UnknownButton_2 = uiradiobutton(app.ModelOrderButtonGroup);
            app.UnknownButton_2.Text = 'Unknown';
            app.UnknownButton_2.Value = true;
            app.LagEditFieldLabel = uilabel(app.ModelConfigurationPanel);
            app.LagEditFieldLabel.Text = 'Lag';
            app.LagEditField = uieditfield(app.ModelConfigurationPanel, 'numeric');
            app.LagEditField.Limits = [1 Inf];
            app.LagEditField.ValueDisplayFormat = '%.0f';
            app.LagEditField.ValueChangedFcn = createCallbackFcn(app, @LagChangedHandler, true);
            app.LagEditField.Value = 15;
            app.InputcolEditFieldLabel = uilabel(app.ModelConfigurationPanel);
            app.InputcolEditFieldLabel.Text = 'Input col.';
            app.InputcolEditField = uieditfield(app.ModelConfigurationPanel, 'text');
            app.InputcolEditField.ValueChangingFcn = createCallbackFcn(app, @InputcolEditFieldValueChanging, true);
            app.OutputcolEditFieldLabel = uilabel(app.ModelConfigurationPanel);
            app.OutputcolEditFieldLabel.Text = 'Output col.';
            app.OutputcolEditField = uieditfield(app.ModelConfigurationPanel, 'text');
            app.OutputcolEditField.ValueChangingFcn = createCallbackFcn(app, @OutputcolEditFieldValueChanging, true);
            app.TextArea = uitextarea(app.ModelConfigurationPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'e.g.'; '[2:4,7],'; '1,etc.'};
            app.ModelEstimationPanel = uipanel(app.UIFigure);
            app.ModelEstimationPanel.Title = 'Model Estimation';
            app.BuildModelButton = uibutton(app.ModelEstimationPanel, 'push');
            app.BuildModelButton.ButtonPushedFcn = createCallbackFcn(app, @BuildModelButtonClicked, true);
            app.BuildModelButton.Text = 'Build Model';
            app.ModelValidationPanel = uipanel(app.UIFigure);
            app.ModelValidationPanel.Title = 'Model Validation';
            app.ComputeConfidenceIntervalsButton = uibutton(app.ModelValidationPanel, 'push');
            app.ComputeConfidenceIntervalsButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeConfidenceIntervalsButtonPushed, true);
            app.ComputeConfidenceIntervalsButton.Text = 'Compute Confidence Intervals';
            app.ComputeResidualsButton = uibutton(app.ModelValidationPanel, 'push');
            app.ComputeResidualsButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeResidualsButtonPushed, true);
            app.ComputeResidualsButton.Enable = 'off';
            app.ComputeResidualsButton.Text = 'Compute Residuals';
            app.AdvOptionsButton = uibutton(app.ModelValidationPanel, 'push');
            app.AdvOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @AdvancedOptionsButtonClickedHandler, true);
            app.AdvOptionsButton.Text = 'Adv. Options';
            app.SaveModelButton = uibutton(app.ModelValidationPanel, 'push');
            app.SaveModelButton.ButtonPushedFcn = createCallbackFcn(app, @SaveModelButtonPushed, true);
            app.SaveModelButton.Text = 'Save Model';
            app.DataProcessingPanel_2 = uipanel(app.UIFigure);
            app.DataProcessingPanel_2.Title = 'Data Processing';
            app.LoadDataDropDownLabel = uilabel(app.DataProcessingPanel_2);
            app.LoadDataDropDownLabel.Text = 'Load Data';
            app.LoadDataDropDown = uidropdown(app.DataProcessingPanel_2);
            app.LoadDataDropDown.Items = {'Select Input Type', 'Data File', 'Load from Environment', 'IDData'};
            app.LoadDataDropDown.ValueChangedFcn = createCallbackFcn(app, @LoadDataDropdown, true);
            app.LoadDataDropDown.Value = 'Select Input Type';
            app.FileLabel = uilabel(app.DataProcessingPanel_2);
            app.FileLabel.Text = '';
            app.LoadsessionButton = uibutton(app.DataProcessingPanel_2, 'push');
            app.LoadsessionButton.ButtonPushedFcn = createCallbackFcn(app, @LoadsessionButtonPushed, true);
            app.LoadsessionButton.Text = 'Load session';
            app.FileLabel_2 = uilabel(app.DataProcessingPanel_2);
            app.FileLabel_2.Text = '';
            app.ClearsessionButton = uibutton(app.DataProcessingPanel_2, 'push');
            app.ClearsessionButton.ButtonPushedFcn = createCallbackFcn(app, @ClearsessionButtonPushed, true);
            app.ClearsessionButton.Text = 'Clear session';
            app.AdvancedOptionsDataProcessingPanel = uipanel(app.UIFigure);
            app.AdvancedOptionsDataProcessingPanel.Title = 'Advanced Options - Data Processing';
            app.PreProcessDataButton = uibutton(app.AdvancedOptionsDataProcessingPanel, 'push');
            app.PreProcessDataButton.ButtonPushedFcn = createCallbackFcn(app, @PreProcessDataButtonPushed, true);
            app.PreProcessDataButton.Text = 'Pre-Process Data';
            app.FileLabel_3 = uilabel(app.AdvancedOptionsDataProcessingPanel);
            app.FileLabel_3.Text = '';
            app.AdvancedOptionsModelConfigurationPanel = uipanel(app.UIFigure);
            app.AdvancedOptionsModelConfigurationPanel.Title = 'Advanced Options - Model Configuration';
            app.AdvancedOptionsModelConfigurationPanel.SizeChangedFcn = createCallbackFcn(app, @AdvancedOptionsModelConfigurationPanelSizeChanged, true);
            app.UITable = uitable(app.AdvancedOptionsModelConfigurationPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.DoneButton = uibutton(app.AdvancedOptionsModelConfigurationPanel, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.EnterOrderEditFieldLabel = uilabel(app.AdvancedOptionsModelConfigurationPanel);
            app.EnterOrderEditFieldLabel.Text = 'Enter Order';
            app.EnterOrderEditField = uieditfield(app.AdvancedOptionsModelConfigurationPanel, 'numeric');
            app.EnterOrderEditField.ValueChangedFcn = createCallbackFcn(app, @EnterOrderEditFieldValueChanged, true);
            app.AdvancedOptionsModelEstimationPanel = uipanel(app.UIFigure);
            app.AdvancedOptionsModelEstimationPanel.Title = 'Advanced Options - Model Estimation';
            app.ModelOrderEstLabel = uilabel(app.AdvancedOptionsModelEstimationPanel);
            app.ModelOrderEstLabel.Text = 'Model Order Est.';
            app.ModelOrderEstDropDown = uidropdown(app.AdvancedOptionsModelEstimationPanel);
            app.ModelOrderEstDropDown.Items = {'Select Order Est. Method', 'User Defined', 'Hypothesis Testing', 'Eigenplots'};
            app.ModelOrderEstDropDown.ItemsData = {'0', '1', '2', '3'};
            app.ModelOrderEstDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelOrderEstChanged, true);
            app.ModelOrderEstDropDown.Enable = 'off';
            app.ModelOrderEstDropDown.Value = '2';
            app.VarianceEstDropDownLabel = uilabel(app.AdvancedOptionsModelEstimationPanel);
            app.VarianceEstDropDownLabel.Text = 'Variance  Est.';
            app.VarianceEstDropDown = uidropdown(app.AdvancedOptionsModelEstimationPanel);
            app.VarianceEstDropDown.Items = {'Select Variance Est. Method', 'Keller''s Solution', 'Maximum Likelihood Estimator'};
            app.VarianceEstDropDown.ValueChangedFcn = createCallbackFcn(app, @VarianceEstDropDownValueChanged, true);
            app.VarianceEstDropDown.Enable = 'off';
            app.VarianceEstDropDown.Value = 'Maximum Likelihood Estimator';
            app.AdvancedOptionsModelValidationPanel = uipanel(app.UIFigure);
            app.AdvancedOptionsModelValidationPanel.Title = 'Advanced Options - Model Validation';
            app.SizeofBootstrapsEditFieldLabel = uilabel(app.AdvancedOptionsModelValidationPanel);
            app.SizeofBootstrapsEditFieldLabel.Text = 'Size of Bootstraps';
            app.SizeofBootstrapsEditField = uieditfield(app.AdvancedOptionsModelValidationPanel, 'numeric');
            app.SizeofBootstrapsEditField.Limits = [0 Inf];
            app.SizeofBootstrapsEditField.ValueChangedFcn = createCallbackFcn(app, @SizeofBootstrapsEditFieldValueChanged, true);
            app.NoofBootstrapsEditFieldLabel = uilabel(app.AdvancedOptionsModelValidationPanel);
            app.NoofBootstrapsEditFieldLabel.Text = 'No. of Bootstraps';
            app.NoofBootstrapsEditField = uieditfield(app.AdvancedOptionsModelValidationPanel, 'numeric');
            app.NoofBootstrapsEditField.Limits = [0 10000];
            app.NoofBootstrapsEditField.ValueChangedFcn = createCallbackFcn(app, @NoofBootstrapsEditFieldValueChanged, true);
            app.CrossValidateButton = uibutton(app.AdvancedOptionsModelValidationPanel, 'push');
            app.CrossValidateButton.ButtonPushedFcn = createCallbackFcn(app, @CrossValidateButtonPushed, true);
            app.CrossValidateButton.Text = 'Cross Validate';
            app.ModelComparisonButton = uibutton(app.AdvancedOptionsModelValidationPanel, 'push');
            app.ModelComparisonButton.ButtonPushedFcn = createCallbackFcn(app, @ModelComparisonButtonPushed, true);
            app.ModelComparisonButton.Text = 'Model Comparison';
            app.CISignificanceEditFieldLabel = uilabel(app.AdvancedOptionsModelValidationPanel);
            app.CISignificanceEditFieldLabel.Text = 'C.I. Significance %';
            app.CISignificanceEditField = uieditfield(app.AdvancedOptionsModelValidationPanel, 'numeric');
            app.CISignificanceEditField.Limits = [90 99.9];
            app.CISignificanceEditField.Value = 90;
            app.BootstrapSizeinEditFieldLabel = uilabel(app.AdvancedOptionsModelValidationPanel);
            app.BootstrapSizeinEditFieldLabel.Text = 'Bootstrap Size in %';
            app.BootstrapSizeinEditField = uieditfield(app.AdvancedOptionsModelValidationPanel, 'numeric');
            app.BootstrapSizeinEditField.Limits = [1 100];
            app.BootstrapSizeinEditField.ValueChangedFcn = createCallbackFcn(app, @BootstrapSizeinEditFieldValueChanged, true);
            app.BootstrapSizeinEditField.Tooltip = {'Enter Size of Bootstraps as percentage of Data'};
            app.BootstrapSizeinEditField.Value = 1;
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Arrow_down.png';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = 'Arrow_down.png';
            app.Image_3 = uiimage(app.UIFigure);
            app.Image_3.ImageSource = 'Arrow_down.png';
            app.Image_4 = uiimage(app.UIFigure);
            app.Image_4.ImageSource = 'Arrow_down.png';
            app.Image_5 = uiimage(app.UIFigure);
            app.Image_5.ImageSource = 'Arrow_down.png';
            app.Image_6 = uiimage(app.UIFigure);
            app.Image_6.ImageSource = 'Arrow_down.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = XPCA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end